classdef contourlevelplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ButtonGroup    matlab.ui.container.ButtonGroup
        logmorelinesinsignalregionButton  matlab.ui.control.RadioButton
        exponetialmorelinesinsignalregionButton  matlab.ui.control.RadioButton
        exponetialmorelinesinnoiseregionButton  matlab.ui.control.RadioButton
        EditField_2    matlab.ui.control.NumericEditField
        cscaleLabel_2  matlab.ui.control.Label
        Button         matlab.ui.control.Button
        EditField      matlab.ui.control.NumericEditField
        cscaleLabel    matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        CallingApp   % Main app object
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, clinenum, scale, lmode)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;

            % Update UI with input values
            app.EditField_2.Value                  = clinenum;
            app.EditField.Value                    = scale;
            if lmode == 1
                app.exponetialmorelinesinsignalregionButton.Value = 1;
            elseif lmode == 2
                app.exponetialmorelinesinnoiseregionButton.Value  = 1;
            elseif lmode == 3
                app.logmorelinesinsignalregionButton              = 1;
            end

            if app.exponetialmorelinesinsignalregionButton.Value
                temp = 1-exp(-[1:app.EditField_2.Value]/app.EditField.Value);
                app.EditField.Visible    = 'on';
                app.UIAxes.YLabel.String = 'contour level = 1-exp(-contour line / scale)';
            elseif app.exponetialmorelinesinnoiseregionButton.Value
                temp = exp([1:app.EditField_2.Value]/app.EditField.Value);
                app.EditField.Visible    = 'on';
                app.UIAxes.YLabel.String = 'contour level = exp(contour line / scale)';
            else
                temp = log(1:app.EditField_2.Value);
                app.EditField.Visible    = 'off';
                app.UIAxes.YLabel.String = 'contour level = ln(contour line)';
            end
            plot(app.UIAxes,temp);

            yticks(app.UIAxes,[min(temp), max(temp)]);
            yticklabels(app.UIAxes,{'noise','signal'});
            app.UIAxes.YGrid = 'on';
            app.UIAxes.XGrid = 'on';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Call main app's public function
            if app.EditField.Value == 0, app.EditField.Value = 1; end

            if app.exponetialmorelinesinsignalregionButton.Value
                temp  = 1-exp(-[1:app.EditField_2.Value]/app.EditField.Value);
                lmode = 1;
                app.EditField.Visible    = 'on';
                app.UIAxes.YLabel.String = 'contour level = 1-exp(-contour line / scale)';
            elseif app.exponetialmorelinesinnoiseregionButton.Value
                temp  = exp([1:app.EditField_2.Value]/app.EditField.Value);
                lmode = 2;
                app.EditField.Visible    = 'on';
                app.UIAxes.YLabel.String = 'contour level = exp(contour line / scale)';
            else
                temp  = log(1:app.EditField_2.Value);
                lmode = 3;
                app.EditField.Visible    = 'off';
                app.UIAxes.YLabel.String = 'contour level = ln(contour line)';
            end
            plot(app.UIAxes,temp);

            yticks(app.UIAxes,[min(temp), max(temp)]);
            yticklabels(app.UIAxes,{'noise','signal'});
            app.UIAxes.YGrid = 'on';
            app.UIAxes.XGrid = 'on';

            updateclines(app.CallingApp,app.EditField.Value,app.EditField_2.Value,lmode);
            
            % Delete the dialog box
%             delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            try app.CallingApp.adjustcontourlevelsButton.Enable = 'on';
            catch, end
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            ButtonPushed(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'contour line')
            ylabel(app.UIAxes, 'contour level = exp(contour line / scale)')
            zlabel(app.UIAxes, 'Z')
            app.cscaleLabel = uilabel(app.UIFigure);
            app.cscaleLabel.Text = 'scale';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [-1000 1000];
            app.EditField.Value = 1;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Apply';
            app.cscaleLabel_2 = uilabel(app.UIFigure);
            app.cscaleLabel_2.Text = 'no. of lines';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [10 100];
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.Value = 10;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.exponetialmorelinesinnoiseregionButton = uiradiobutton(app.ButtonGroup);
            app.exponetialmorelinesinnoiseregionButton.Text = 'exponetial (more lines in noise region)';
            app.exponetialmorelinesinnoiseregionButton.Value = true;
            app.exponetialmorelinesinsignalregionButton = uiradiobutton(app.ButtonGroup);
            app.exponetialmorelinesinsignalregionButton.Text = 'exponetial (more lines in signal region)';
            app.logmorelinesinsignalregionButton = uiradiobutton(app.ButtonGroup);
            app.logmorelinesinsignalregionButton.Text = 'log (more lines in signal region)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = contourlevelplot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end