classdef displayapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        ParametersMenu                  matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        FilterMenu_2                    matlab.ui.container.Menu
        kspaceMenu                      matlab.ui.container.Menu
        HanningMenu                     matlab.ui.container.Menu
        HammingMenu                     matlab.ui.container.Menu
        NoFilterMenu                    matlab.ui.container.Menu
        GuassianMenu                    matlab.ui.container.Menu
        t1Menu                          matlab.ui.container.Menu
        t2Menu                          matlab.ui.container.Menu
        BothMenu                        matlab.ui.container.Menu
        NoneMenu                        matlab.ui.container.Menu
        ExponentialMenu_3               matlab.ui.container.Menu
        t1Menu_2                        matlab.ui.container.Menu
        t2Menu_2                        matlab.ui.container.Menu
        BothMenu_2                      matlab.ui.container.Menu
        NoneMenu_2                      matlab.ui.container.Menu
        ParametersMenu_2                matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        ResetMenu                       matlab.ui.container.Menu
        TiltcorrectionMenu              matlab.ui.container.Menu
        plusMenu                        matlab.ui.container.Menu
        minusMenu                       matlab.ui.container.Menu
        zeroMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        LinewidthLabel                  matlab.ui.control.Label
        ExpEditField                    matlab.ui.control.NumericEditField
        ExpEditFieldLabel               matlab.ui.control.Label
        GaussEditField                  matlab.ui.control.NumericEditField
        GaussEditFieldLabel             matlab.ui.control.Label
        infolabel                       matlab.ui.control.Label
        CurrentDS                       matlab.ui.control.Label
        WsSliceField1                   matlab.ui.control.NumericEditField
        SliceLabel                      matlab.ui.control.Label
        toEditFieldLabel_4              matlab.ui.control.Label
        WsSliceField2                   matlab.ui.control.NumericEditField
        wsRevertAllButton               matlab.ui.control.Button
        wsApplyButton                   matlab.ui.control.Button
        WsRowField2                     matlab.ui.control.NumericEditField
        toEditField_7Label              matlab.ui.control.Label
        WsRowField1                     matlab.ui.control.NumericEditField
        RowsEditFieldLabel              matlab.ui.control.Label
        WsColField1                     matlab.ui.control.NumericEditField
        ColsEditFieldLabel              matlab.ui.control.Label
        toEditFieldLabel_3              matlab.ui.control.Label
        WsColField2                     matlab.ui.control.NumericEditField
        WaterSuppressionLabel           matlab.ui.control.Label
        adjustcontourlevelsButton       matlab.ui.control.Button
        BackgroundLabel                 matlab.ui.control.Label
        ColormapDropDown_2Label         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        SelectSlicesPanel               matlab.ui.container.Panel
        ColumnrangeEditField            matlab.ui.control.NumericEditField
        RowrangeEditField               matlab.ui.control.NumericEditField
        ColumnrangeEditFieldLabel       matlab.ui.control.Label
        RowrangeEditFieldLabel          matlab.ui.control.Label
        InputROIformultivoxelplotLabel  matlab.ui.control.Label
        toEditFieldLabel_2              matlab.ui.control.Label
        toEditField_2                   matlab.ui.control.NumericEditField
        toEditField                     matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        SaveFormatLabel                 matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        TIFButton                       matlab.ui.control.RadioButton
        PPTButton                       matlab.ui.control.RadioButton
        PDFButton                       matlab.ui.control.RadioButton
        ViewButton                      matlab.ui.control.Button
        SaveButton_5                    matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        SaveallNode                     matlab.ui.container.TreeNode
        Slice1Node                      matlab.ui.container.TreeNode
        Slice2Node                      matlab.ui.container.TreeNode
        Slice3Node                      matlab.ui.container.TreeNode
        Slice4Node                      matlab.ui.container.TreeNode
        Slice5Node                      matlab.ui.container.TreeNode
        Slice6Node                      matlab.ui.container.TreeNode
        Slice7Node                      matlab.ui.container.TreeNode
        Slice8Node                      matlab.ui.container.TreeNode
        toEditField_3Label_3            matlab.ui.control.Label
        toEditField_3Label_2            matlab.ui.control.Label
        toLabel                         matlab.ui.control.Label
        toEditField_5                   matlab.ui.control.NumericEditField
        RangeppmEditField               matlab.ui.control.NumericEditField
        RangeppmEditFieldLabel          matlab.ui.control.Label
        SliceSpinner                    matlab.ui.control.Spinner
        SliceSpinnerLabel               matlab.ui.control.Label
        ScalewrtSpinner                 matlab.ui.control.Spinner
        ScalewrtSpinnerLabel            matlab.ui.control.Label
        MetMapDropDown                  matlab.ui.control.DropDown
        MetMapLabel                     matlab.ui.control.Label
        SaveButton_4                    matlab.ui.control.Button
        PopoutButton_2                  matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ImagButton                      matlab.ui.control.RadioButton
        RealButton                      matlab.ui.control.RadioButton
        MagnitudeButton                 matlab.ui.control.RadioButton
        F1AdjustField                   matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        F2AdjustField                   matlab.ui.control.NumericEditField
        BringtoLabel                    matlab.ui.control.Label
        EnterNameEditField              matlab.ui.control.EditField
        EnterNameEditFieldLabel         matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        WhereisDropDown                 matlab.ui.control.DropDown
        WhereisDropDownLabel            matlab.ui.control.Label
        F2ppmEditField                  matlab.ui.control.NumericEditField
        F2ppmEditFieldLabel             matlab.ui.control.Label
        F1HzEditField                   matlab.ui.control.NumericEditField
        F1HzEditFieldLabel              matlab.ui.control.Label
        toEditField_4                   matlab.ui.control.NumericEditField
        toEditField_4Label              matlab.ui.control.Label
        F1rangeEditField                matlab.ui.control.NumericEditField
        F1rangeEditFieldLabel           matlab.ui.control.Label
        toEditField_3                   matlab.ui.control.NumericEditField
        toEditField_3Label              matlab.ui.control.Label
        F2rangeEditField                matlab.ui.control.NumericEditField
        F2rangeLabel                    matlab.ui.control.Label
        ColormapDropDown_2              matlab.ui.control.DropDown
        ContourLineFillingSwitch        matlab.ui.control.RockerSwitch
        ContourLineFillingSwitchLabel   matlab.ui.control.Label
        LinethicknessSpinner            matlab.ui.control.Spinner
        LinethicknessSpinnerLabel       matlab.ui.control.Label
        ContourLinesSpinner             matlab.ui.control.Spinner
        ContourLinesSpinnerLabel        matlab.ui.control.Label
        NoiseScaleSpinner               matlab.ui.control.Spinner
        NoiseScaleSpinnerLabel          matlab.ui.control.Label
        SignalScaleSpinner              matlab.ui.control.Spinner
        SignalScaleSpinnerLabel         matlab.ui.control.Label
        DriftCorrectionLabel            matlab.ui.control.Label
        AdjustButton                    matlab.ui.control.Button
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        SaveButton_3                    matlab.ui.control.Button
        MultiuseLabel                   matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        PopoutButton                    matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SliceviewButtonGroup            matlab.ui.container.ButtonGroup
        AllButton                       matlab.ui.control.RadioButton
        SingleButton                    matlab.ui.control.RadioButton
        ParametersLabel                 matlab.ui.control.Label
        SpectrumviewButtonGroup         matlab.ui.container.ButtonGroup
        MultivoxelcontourButton         matlab.ui.control.RadioButton
        SinglevoxelcontourButton        matlab.ui.control.RadioButton
        SinglevoxelintensityButton      matlab.ui.control.RadioButton
        Button                          matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        % input params made optional with process_options
        OptionsApp;
        filepath;
        DialogApp;
        
        T        = 123.23;
        bw2      = 1190;
        bw1      = 1000;
        zp2      = 4.72;
        zp1      = 0;
        met      = 'NAA';
        f2range  = [1.5,4.5];
        f1range  = [-50,50];
        metlist  = {};
        %clog = 1;
        recon; %original input data
        wsdata; % with watersuppress
        mode     = 'FT';

        % dimension of ws_recon
        ny;
        nx;
        nz;
        nt2; 
        nt1; 
       
        %color axis
        %contour
        cmin;
        cmax;
        rmin;
        rmax; 
        %intensity
        cmin2;
        cmax2;
        rmin2;
        rmax2; 
        rlmin;
        rlmax;
        %slice num
        sl; 
        %signal and noise
%         s1 = 1.3; 
%         s1ref = 1.3;
%         s2 = 0.72;
%         s2ref = 0.72; 
        s1    = .7; %exp contour lvls
        s1ref = .7; %exp contour lvls
        s2    = 3.2; %exp contour lvls
        s2ref = 3.2; %exp contour lvls
        ref1;
        ref2;
        flag;
        
        %intensity plot func
        s_handle; 
        
        % metabolite dictionary
        rdict; 
        tol1  = 25; %Hz
        %generate dictionary (currently only for JPRESS, need to add COSY here)
        mdict; 
        metmaps;
        %dictionary id for the default metabolite map to display
%         sid = 0; 
        
        %matlab app control
        %click pixel
        xpos = 8;
        ypos = 8;
        
        %the input/data selection app
        CallingApp;
        
        %the export data interface
        ExportApp;

        %shift adjust
        %generated with adjustmap function
        admap;
        %keep track of already adjusted data
        %adcheck;
        xadjust;
        yadjust;

        %data adjust
        filter = "No";
        Gaussfilter = "No";
        Expfilter = "No";
        tilt = 0;
        wsed; %keep track of watershed status
    end
    
    properties (Access = public)
        %the multivox viewing app
        folder;
        multidisplay; 
        reconmethod;
        c_dark;
        cmaps = {'parula','jet','hot','gray','copper','bone','hsv','colorcube','dark'};
        %multivox range
        xtop;
        xbottom;
        ytop;
        ybottom;
        %background color for spectrum
        bg = 'black';
        %axis range for spectrum from Make Dictionary
        r11;
        r12;
        r21;
        r22;
        cdata;
        ax1;
        ax2;
        ID1;
        ID2;
        keySet   = {'NAA',      'Cho',      'Cre30',    'Cre39',    'Glx',      'mi',       'lac',      'fat',    'wat'};
        valueSet = {[1.91,2.11],[3.09,3.29],[2.93,3.13],[3.81,4.01],[2.10,2.50],[3.42,3.62],[1.23,1.43],[1.1,1.6],[4.52,4.92]};
        M;
        ix11;
        ix12;
        %contour filling
        fill = 'off';
        %contour linewidth
        lw          = 1; 
        lwref       = 1;
        %contour line num
        clinenum    = 40; 
        clinenumref = 40; 
        cscale      = 20;
        cscaleref   = 20;
        lmode       = 1;
        lmoderef    = 1;
        clines;
        gauslw = 2;
        explw = 2;
    end
    
    methods (Access = private)

        function populatefields(app)
            if app.nz>1
                app.ScalewrtSpinner.Value = ceil(app.nz/2+1);
                app.SliceSpinner.Value    = ceil(app.nz/2+1);
                app.sl                    = ceil(app.nz/2+1);
            else
                app.ScalewrtSpinner.Value = 1;
                app.SliceSpinner.Value    = 1;
                app.sl                    = 1;
            end
            app.F1rangeEditField.Value         = app.f1range(1);
            app.toEditField_3.Value            = app.f1range(2);
            app.F2rangeEditField.Value         = app.f2range(1);
            app.toEditField_4.Value            = app.f2range(2);
            app.Button_2.BackgroundColor       = app.bg;
            app.ContourLinesSpinner.Value      = app.clinenum;
            app.SignalScaleSpinner.Value       = 0;
            app.NoiseScaleSpinner.Value        = 0;
            app.LinethicknessSpinner.Value     = 1;
            app.ContourLineFillingSwitch.Value = 'Off';
            updateaxis(app);
        end

        function updateaxis(app)
            app.ax1   = app.bw1/2/app.nt1*(-app.nt1/2:1:app.nt1/2-1) + app.zp1;
            app.ax2   = (-1/2:1/app.nt2:1/2-1/app.nt2).*app.bw2./app.T + app.zp2;
            app.F1rangeEditField.Limits = [min(app.ax1),max(app.ax1)];
            app.F2rangeEditField.Limits = [min(app.ax2),max(app.ax2)];
            app.toEditField_3.Limits    = [min(app.ax1),max(app.ax1)];
            app.toEditField_4.Limits    = [min(app.ax2),max(app.ax2)];
            ppmHz2index_handle(app);
        end

        function ppmHz2index_handle(app)
            app.ID1 =@(x) round(1+(x-app.ax1(1))/(app.ax1(2)-app.ax1(1)));
            app.ID2 =@(x) round(1+(x-app.ax2(1))/(app.ax2(2)-app.ax2(1)));
            MakeMetDictionary(app);
        end
        
        function MakeMetDictionary(app)
            % metabolite dictionary
            app.ix11  = app.ID1(-app.tol1);
            app.ix12  = app.ID1( app.tol1);
            app.M     = containers.Map(app.keySet,app.valueSet);
            preparerange(app);
        end

        function preparerange(app)
            % define F1 and F2 range to plot
            app.r21   = app.ID2(app.f2range(1)); 
            app.r22   = app.ID2(app.f2range(2));
            app.r11   = app.ID1(app.f1range(1));
            app.r12   = app.ID1(app.f1range(2));
            initializeparams(app);
        end

        function initializeparams(app)
            % some initialization
            app.ref1  = app.ID2(2.83);% reference peaks for default scale
            app.ref2  = app.ID2(3.23);
            temp      = abs(app.cdata(app.ypos,app.xpos,app.sl,app.r21:app.r22,app.r11:app.r12));
            app.cmax2 = 5*max(abs(app.cdata(app.ypos,app.xpos,app.sl,app.ref1:app.ref2,app.ix11:app.ix12)),[],'all');
            app.cmin2 = min(temp(:)+eps); app.rmin2 = app.cmin2; app.rmax2 = app.cmax2;
            
            %contour line func, clinenum = num of clines
            if app.lmode == 1
                app.clines = @(scale, clinenum) rescale(1-exp(-[1:clinenum]/app.cscale),scale(1),scale(2));
            elseif app.lmode == 2
                app.clines = @(scale, clinenum) rescale(exp([1:clinenum]/app.cscale),scale(1),scale(2));
            elseif app.lmode == 3
                app.clines = @(scale, clinenum) rescale(log(1:clinenum),scale(1),scale(2));
            end
        end


        function updatesliceview(app)

            %acquire metabolite map
            metname = app.MetMapDropDown.Value;
            temp    = app.M(app.MetMapDropDown.Value);
            
            metmap  = sum(sum(abs(app.cdata(:,:,:,app.ID2(temp(1)):app.ID2(temp(2)),app.ix11:app.ix12)),5),4);

            app.metmaps = metmap;
            if app.nz>1
                [~, ~, idz]          = ind2sub(size(metmap),find(metmap(:)==max(metmap(:)),1));
                [app.cmin,app.rmin]  = deal(min(metmap(:,:,idz),[],'all')); 
                [app.cmax,app.rmax]  = deal(max(metmap(:,:,idz),[],'all'));
            else
                [app.cmin,app.rmin]  = deal(min(metmap(:)));
                [app.cmax,app.rmax]  = deal(max(metmap(:)));
            end

            temp                        = app.M(app.MetMapDropDown.Value);
            app.RangeppmEditField.Value = temp(1);
            app.toEditField_5.Value     = temp(2);

            %scale wrt
            scaleidx                    = app.ScalewrtSpinner.Value;
            app.Label.Text = strcat('scaled w.r.t. slice ', int2str(scaleidx), ' Metmap: ');

            %datatip
            dcm_obj        = datacursormode(app.UIFigure);
            dcm_obj.Enable = 'off';

            %all slice view
            if app.AllButton.Value
                app.SliceSpinner.Enable = 'off';
             
                %tiled image
                out = imtile(mat2cell(app.metmaps,app.ny,app.nx,ones(1,app.nz)),'BorderSize',2); 
                imagesc(app.UIAxes, out);

                %axis and titles
                app.UIAxes.XTick           = [];
                app.UIAxes.YTick           = [];
                app.UIAxes.XLabel.String   = '';
                app.UIAxes.YLabel.String   = '';
                app.UIAxes.YLabel.FontSize = 8;
                app.UIAxes.Title.String    = strcat("All-slice view"); 
                app.UIAxes.DataAspectRatio = [1 1 1]; %to make plot fit axis

                %colormap
                app.cmin = min(app.metmaps(:,:,scaleidx),[],'all');
                app.cmax = max(app.metmaps(:,:,scaleidx),[],'all');
                app.UIAxes.CLim = [app.cmin, app.cmax];
                
                %display subtitle for each slice
                [sz1,sz2] = size(out);
                yn = sz1/(app.ny+2*2);
                xn = sz2/(app.nx+2*2);
                count = 0;
                for i=1:yn
                    for j=1:xn
                        count = count+1;
                        if count<=app.nz
                            text(app.UIAxes,(j-1)*(app.nx+2*2)+2,(i-1)*(app.ny+2*2)+2,...
                            sprintf('Slice: %g',count),'Color',[1 1 1],'FontSize',9);
                        end
                    end
                end

           end
           
            %single slice view
            if app.SingleButton.Value
                app.SliceSpinner.Enable = 'on';
                sliceidx = app.SliceSpinner.Value;
                app.sl   = sliceidx;
                out      = app.metmaps(:,:,sliceidx);
                cla(app.UIAxes,'reset')
                imagesc(app.UIAxes, out); 
                app.UIAxes.Title.String    = strcat("Single slice: #", int2str(sliceidx));
                app.Label.Text             = strcat('scaled w.r.t. slice', int2str(scaleidx), '    Metmap: ',metname);
                app.UIAxes.XLimitMethod    = 'tight';
                app.UIAxes.YLimitMethod    = 'tight';
                app.UIAxes.YTickMode       = 'auto';
                app.UIAxes.XTickMode       = 'auto';
                app.UIAxes.XLabel.String   = 'columns';
                app.UIAxes.YLabel.String   = 'rows';
                app.UIAxes.FontSize        = 10;
                app.UIAxes.DataAspectRatio = [1 1 1]; %to make plot fit axis
%                 colorbar(app.UIAxes);
                
                %datatip selector
                dcm_obj.Enable = 'on';
                % Set update function for datatip change
                set(dcm_obj,'UpdateFcn',@myupdatefcn)
                %disp(get(0,'userdata'))
                
                %colormap
                app.cmin = min(app.metmaps(:,:,scaleidx),[],'all');
                app.cmax = max(app.metmaps(:,:,scaleidx),[],'all');
                app.UIAxes.CLim = [app.cmin, app.cmax];
            end
            
            colormap(app.UIAxes,app.ColormapDropDown.Value);

            %datatip update
            function output_txt = myupdatefcn(~,event_obj)
                pos  = get(event_obj, 'Position');
                %set(0,'userdata',pos); %get     
                h    = get(event_obj,'Target');
                temp = class(h);
                %Z only availble for spec data
                if strcmp(temp(end-6:end),'Contour')
                    X          = get(h,'XData'); 
                    Y          = get(h,'YData'); 
                    Z          = get(h,'ZData');
                    Level      = Z(Y==pos(2), X==pos(1));
                    output_txt = {['F2: ' num2str(pos(1)) ' (ppm)'], ['F1: ' num2str(pos(2)) ' (Hz)'], ['value: ' num2str(Level) ' (a.u.)']};
                elseif strcmp(temp(end-4:end),'Image')
                    %for slice view
                    app.xpos   = pos(1);
                    app.ypos   = pos(2);
                    output_txt = {['column: ' num2str(pos(1))], ['row: ' num2str(pos(2))]};
                    %update spectrum based on datatip select
                    if pos(1)==app.ny(end) && pos(2)==app.nx(end)
                        % for some reason, matlab is using last [col,row] before using 
                        % correct position when shwitching back from contour
                    else, updatespectrum(app);
                    end
                end
            end
        end 

        function updatespectrum(app)

            spec = squeeze(app.cdata(app.ypos,app.xpos,app.sl,:,:));
            spec = spec(app.r21:app.r22,app.r11:app.r12);
            if app.MagnitudeButton.Value
                spec = abs(spec);
                clim = [app.s1*std(spec(:)),app.s2*std(spec(:))];
                cl   = app.clines(clim,app.ContourLinesSpinner.Value);
            elseif app.RealButton.Value
                spec = real(spec);
                temp = max(std(spec(spec>0)),std(abs(spec(spec<0))));
                clim = [app.s1*temp,app.s2*temp];
                cl   = app.clines(clim,app.ContourLinesSpinner.Value);
                cl   = [-cl cl];
            elseif app.ImagButton.Value
                spec = imag(spec);
                temp = max(std(spec(spec>0)),std(spec(spec<0)));
                clim = [app.s1*temp,app.s2*temp];
                cl   = app.clines(clim,app.ContourLinesSpinner.Value);
                cl   = [-cl cl];
            end 

            % single vox contour
            if app.SinglevoxelcontourButton.Value
                % contour plot
                contour(app.UIAxes2, app.ax2(app.r21:app.r22), app.ax1(app.r11:app.r12), spec.',...
                    cl,'-','linewidth',app.lw,'fill',app.fill);
                % command line output
                disp(strcat('Single-voxel contour plot rendered at pos: Row: ', num2str(app.ypos),' Col: ',num2str(app.xpos),' Slice: ', num2str(app.sl)));
            % single vox intensity
            elseif app.SinglevoxelintensityButton.Value
                % intensity plot
                imagesc(app.UIAxes2, app.ax2(app.r21:app.r22),app.ax1(app.r11:app.r12),spec.');
                % command line output
                disp(strcat('Single-voxel intensity plot rendered at pos: Row: ', num2str(app.ypos),' Col: ',num2str(app.xpos),' Slice: ', num2str(app.sl)));
            end

            % setting axis properties
            % colormap and clim
            if strcmp(app.ColormapDropDown_2.Value,'dark')
                colormap(app.UIAxes2, app.c_dark);
                if app.SinglevoxelcontourButton.Value && app.MagnitudeButton.Value
                    caxis(app.UIAxes2, [-2*max(abs(cl)),2*max(abs(cl))]);
                end
                if app.SinglevoxelintensityButton.Value
                    caxis(app.UIAxes2, [-2*clim(2),2*clim(2)]);
                end
            else
                colormap(app.UIAxes2, app.ColormapDropDown_2.Value);
                caxis(app.UIAxes2, clim);
            end
            colorbar(app.UIAxes2);
            %figure axis
            app.UIAxes2.Color         = app.bg;
            app.UIAxes2.XLabel.String = 'F_{2}(ppm)'; 
            app.UIAxes2.XDir          = 'reverse';
            app.UIAxes2.YLabel.String = 'F_{1}(Hz)';
            %title
            voxtitle = strcat('Voxel: Row: ', num2str(app.ypos),' Col: ',num2str(app.xpos),' Slice: ', num2str(app.sl));
            app.UIAxes2.Title.String = voxtitle;
            %axis range
            MakeRangeDictionary(app);
            % define F1 and F2 range to plot
            app.r21   = app.rdict{1}.r21; 
            app.r22   = app.rdict{1}.r22;
            app.r11   = app.rdict{1}.r11;
            app.r12   = app.rdict{1}.r12;


            %adjust 
            %reading from admap which records the peak lvl pos of the unadjusted 
%             %if app.adcheck(app.xpos, app.ypos, app.sl) == 0
%                 ad = app.admap{app.xpos,app.ypos,app.sl};
%                 xadjust = ad(1);
%                 yadjust = ad(2);
%                 adjust(app,xadjust,yadjust);
%                 disp('adjusted at ');
%                 disp(xadjust);
%                 disp(yadjust);
%                 %app.adcheck(app.xpos, app.ypos, app.sl) = 1;
%             %end

            %calculate level max pt
            spec1 = abs(squeeze(app.cdata(app.ypos,app.xpos,app.sl,app.ID2(1.5):app.ID2(2.5),app.ID1(-30):app.ID1(30))));  
            cm    = contourc(app.ax2(app.ID2(1.5):app.ID2(2.5)),app.ax1(app.ID1(-30):app.ID1(30)), spec1.');
            %convert contour matrix to xcoord, ycoord cx, cy grouped by
            %contour levels cz (cell arrays)
            % cm to x,y,z returns the x and y coordinates of contours in a contour
            % matrix and their corresponding z values. cm is the contour matrix given by 
            % the contour function. 
            % based on code by Chad Greene, August 2013.
            m(1)=1; n=1;  
            try
                while n<length(cm)
                    n=n+1; m(n) = m(n-1)+cm(2,m(n-1))+1;                     
                end
            catch
            end
            for nn = 1:n-2
                cx{nn} = cm(1,m(nn)+1:m(nn+1)-1); 
                cy{nn} = cm(2,m(nn)+1:m(nn+1)-1); 
                cz(nn) = cm(1,m(nn));
            end
            %points corresponding to the contour line 
            for n = find(cz==max(cz))%max level in selected region
                xl = cx{n};
                yl = cy{n};
            end
            app.F2ppmEditField.Value = mean(xl);
            app.F1HzEditField.Value  = mean(yl);
        end
      
        function adjust(app,xadjust,yadjust)
            in = app.wsdata;
            if ndims(in)==4, in = permute(in,[1,2,5,3,4]); end
            if ismatrix(in), if size(in,1) < size(in,2), in = permute(in,[2,1]); end; in = permute(in,[3,4,5,1,2]); end
            f1ref = [app.F1AdjustField.Value, 0];
            f2ref = [app.F2AdjustField.Value, app.F2AdjustField.Value];
            %f1 y
            id1_1   = find(abs(app.ax1-f1ref(1)) == min(abs(app.ax1-f1ref(1))));
            tmp_ax1 = app.ax1 + (yadjust-f1ref(1)); %this is temp, don't change to app.ax1 to keep the property unchanged
            id1_2   = find(abs(tmp_ax1-f1ref(1)) == min(abs(tmp_ax1-f1ref(1))));
            fprintf('f1 shift correction: %d positions\n',id1_2-id1_1);

            %f2 x
            id2_1   = find(abs(app.ax2-f2ref(1)) == min(abs(app.ax2-f2ref(1))));
            tmp_ax2 = app.ax2 - (xadjust-f2ref(1));
            id2_2   = find(abs(tmp_ax2-f2ref(1)) == min(abs(tmp_ax2-f2ref(1))));
            fprintf('f2 shift correction: %d positions\n',id2_1-id2_2);
            
            out       = circshift(in,[0,0,0,id2_1-id2_2,id1_2-id1_1]);
            app.wsdata = squeeze(out);
            clear in;
            updateapp(app);
        end
        
        %toggle control button/input fields depending on data mode
        function onoff(app, single, multi)
            %multi
                app.ColumnrangeEditFieldLabel.Visible      = multi; 
                app.ColumnrangeEditField.Visible           = multi;
                app.toEditField_2.Visible                  = multi;
                app.toEditFieldLabel.Visible               = multi;
                app.RowrangeEditFieldLabel.Visible         = multi;
                app.toEditField.Visible                    = multi;
                app.RowrangeEditField.Visible              = multi;
                app.InputROIformultivoxelplotLabel.Visible = multi; 
            %single
                
                app.SaveButton_2.Enable             = single;
                app.SaveButton_3.Enable             = single;
                app.SaveButton_4.Enable             = single;
                app.PopoutButton.Enable             = single;
                app.PopoutButton_2.Enable           = single;
              
                app.F1rangeEditField.Enable         = single;
                app.F2rangeEditField.Enable         = single;
                app.SliceSpinner.Enable             = single;
                app.SliceviewButtonGroup.Enable     = single;
                app.NoiseScaleSpinner.Enable        = single;
                app.SignalScaleSpinner.Enable       = single;
                app.MetMapDropDown.Enable           = single;
                app.toEditField_4.Enable            = single;
                app.toEditField_3.Enable            = single;
                app.ColormapDropDown.Enable         = single;
                app.ContourLineFillingSwitch.Enable = single;
                app.ContourLinesSpinner.Enable      = single;
                app.LinethicknessSpinner.Enable     = single;
                app.ScalewrtSpinner.Enable          = single;
                app.ContourLineFillingSwitch.Enable = single;
                app.ContourLinesSpinner.Enable      = single;
                app.LinethicknessSpinner.Enable     = single;
                app.ScalewrtSpinner.Enable          = single;
                app.ScalewrtSpinner.Enable          = single;
                app.RangeppmEditField.Enable        = single;
                app.toEditField_5.Enable            = single;
                app.WhereisDropDown.Enable          = single;
                app.F2ppmEditField.Enable           = single;
                app.F1HzEditField.Enable            = single;
                app.F2AdjustField.Enable         = single;
                app.F1AdjustField.Enable                = single;
                app.AdjustButton.Enable             = single;
                app.ButtonGroup.Enable              = single;
                app.ColormapDropDown_2.Enable       = single;
                app.Button_2.Enable                 = single;
        end

        function f = drawslice(app,sliceidx,save)
            %create slice
            f = figure(sliceidx + 100);
            %auto full-screen
            f.Units = 'normalized';
            f.OuterPosition = [0 0 1 1];
            %skip visulization display if it's save rather than view
            if save, f.Visible = 'Off'; else, f.Visible = 'On'; end
            %generate tiled images
            rows = [app.RowrangeEditField.Value,app.toEditField.Value];
            clms = [app.ColumnrangeEditField.Value,app.toEditField_2.Value];

            cfig = tiledlayout(numel(rows(1):rows(2)),numel(clms(1):clms(2)),'TileSpacing','Compact','Padding','Compact');
            cfig.Title.String = strcat('Multi-voxel contour plot of slice ',num2str(sliceidx));
            for i=rows(1):rows(2)
                for j=clms(1):clms(2)
                    spec = abs(squeeze(app.cdata(i,j,sliceidx,app.r21:app.r22,app.r11:app.r12))).';
                    temp = std(spec(:));
                    cl   = app.clines([app.s1*temp,app.s2*temp],app.ContourLinesSpinner.Value);
                    
                    nexttile(cfig); 
                    contour(app.ax2(app.r21:app.r22), app.ax1(app.r11:app.r12), spec, ...
                    cl, '-','linewidth',app.lw,'fill',app.fill);

                    set(gca,'xdir','reverse');
                    set(gca,'Color',app.bg,'XColor','black','YColor','black','FontWeight','normal','FontSize',7); grid on; 
                    title(gca,strcat("Row: ",num2str(i)," Col: ",num2str(j)));
                    
                    if strcmp(app.ColormapDropDown_2.Value,'dark')
                        colormap(app.c_dark);
                        if app.MagnitudeButton.Value
                            caxis([-1.5*max(abs(cl)),1.5*max(abs(cl))]);
                        end
                    else
                        colormap(app.ColormapDropDown_2.Value);
                    end
                end
                %speed up by skipping frames for visualization
                drawnow limitrate
            end
        end
        function MakeRangeDictionary(app)
            app.rdict{1}.r21 = find(app.ax2 >= app.f2range(1),1,'first'); 
            app.rdict{1}.r22 = find(app.ax2 <= app.f2range(2),1,'last');
            app.rdict{1}.r11 = find(app.ax1 >= app.f1range(1),1,'first');
            app.rdict{1}.r12 = find(app.ax1 <= app.f1range(2),1,'last');
            
            if app.f2range(1) >0
                app.rdict{2}.r21 = find(app.ax2 >= 0,1,'first'); 
                app.rdict{2}.r22 = find(app.ax2 <= 9,1,'last');
                app.rdict{2}.r11 = find(app.ax1 >= app.f1range(1),1,'first');
                app.rdict{2}.r12 = find(app.ax1 <= app.f1range(2),1,'last');
            else
                app.rdict{2}.r21 = find(app.ax2 >= 1.5,1,'first'); 
                app.rdict{2}.r22 = find(app.ax2 <= 4.5,1,'last');
                app.rdict{2}.r11 = find(app.ax1 >= app.f1range(1),1,'first');
                app.rdict{2}.r12 = find(app.ax1 <= app.f1range(2),1,'last');
                app.rdict{3}.r21 = find(app.ax2 >= 1.5,1,'first'); 
                app.rdict{3}.r22 = find(app.ax2 <= 4.5,1,'last');
                app.rdict{3}.r11 = find(app.ax1 >= app.f1range(1),1,'first');
                app.rdict{3}.r12 = find(app.ax1 <= app.f1range(2),1,'last');
            end
        end
    end
    
    methods (Access = public)
        % fftsn and ifftsn definisions
        function out = fftsn(x,dim)
            out = (1/sqrt(size(x,dim)))*fftshift(fft(fftshift(x,dim),[],dim),dim);
        end
        function out = ifftsn(x,dim)
            out = sqrt(size(x,dim))*ifftshift(ifft(ifftshift(x,dim),[],dim),dim);
        end
        function updateparams(app, bw1, bw2, zp1, zp2, T)
            app.bw1 = bw1;
            app.bw2 = bw2;
            app.zp1 = zp1;
            app.zp2 = zp2;
            app.T   = T;
            updateaxis(app)
        end

        function updateapp(app)
            %data update
            %%tilt 
            %tilts ws directly
            app.cdata = app.wsdata;
           if app.plusMenu.Checked == "on"
               if app.tilt ~= 2
                  app.wsdata = tiltcorrect5d(app.wsdata,2-app.tilt,840);
                  app.tilt = 2;
%                   disp('tilt corrected (+2)');
               end
           elseif app.minusMenu.Checked == "on"
               if app.tilt ~= -2
                  app.wsdata = tiltcorrect5d(app.wsdata,-2-app.tilt,840);
                  app.tilt = -2;
%                   disp('tilt corrected (-2)');
               end
           else
               if app.tilt ~= 0
                  app.wsdata = tiltcorrect5d(app.wsdata,-app.tilt,840)
                  app.tilt = 0;
               end
%               disp('back to untilted input');
           end
           app.cdata = app.wsdata; %done tilting
            
           %modify only cdata for display, filters reapplied everytime
           %updateapp is called
            %%k-space - filters
            if app.HanningMenu.Checked == "on"
                 app.filter = "Hann";
                 filt = hanning(app.ny).*hanning(app.nx).'.*permute(hanning(app.nz),[2,3,1]);
                 app.cdata = ifftsn(ifftsn(ifftsn(fftsn(fftsn(fftsn(app.wsdata,1),2),3).*filt ,1),2),3);
            elseif app.HammingMenu.Checked == "on"
                filt = hamming(app.ny).*hamming(app.nx).'.*permute(hamming(app.nz),[2,3,1]);
                    app.filter = "Hamm"
                    %tranform to fourier domain, apply filter and then transform back to image domain
                    app.cdata = ifftsn(ifftsn(ifftsn(fftsn(fftsn(fftsn(app.wsdata,1),2),3).*filt ,1),2),3); 
            else
                    app.filter = "No";
            end
            
            %% Gauss filters
            app.GaussEditField.Enable = "on";
            filt1 = exp(-(pi*app.gauslw*([0:app.nt1-1]-app.nt1/2)/app.bw1).^2/(2*log(2))); % 1x64
            filt2 = exp(-(pi*app.gauslw*([0:app.nt2-1]/app.bw2)).^2/(2*log(2)));  % 1x512
            if app.t1Menu.Checked == "on"
                app.cdata = app.cdata.*permute(filt1,[3,4,5,1,2]);
                app.Gaussfilter = "t1";
            elseif app.t2Menu.Checked == "on"
                app.cdata = ffts(iffts(app.cdata,4).*permute(filt2,[3,4,5,2,1]),4);
                app.Gaussfilter = "t2";
            elseif app.BothMenu.Checked == "on"
                app.cdata = ffts(iffts(app.cdata,5).*permute(filt1,[3,4,5,1,2]),5);
                        app.cdata = ffts(iffts(app.cdata,4).*permute(filt2,[3,4,5,2,1]),4);
                app.Gaussfilter = "Both";
            else
                app.Gaussfilter = "No";
                app.GaussEditField.Enable = "off";
                app.GaussEditField.Value = 0;
            end

            %% Exponential filters
            app.ExpEditField.Enable = "on";
            filte1 = exp(-pi*app.explw*abs(([0:app.nt1-1]-app.nt1/2))/app.bw1);
            filte2 = exp(-pi*app.explw*abs([0:app.nt2-1]/app.bw2));
            if app.t1Menu_2.Checked == "on"
                app.cdata = ffts(iffts(app.cdata,5).*permute(filte1,[3,4,5,1,2]),5);
                app.Expfilter = "t1";
            elseif app.t2Menu_2.Checked == "on"
                app.cdata = ffts(iffts(app.cdata,4).*permute(filte2,[3,4,5,2,1]),4);
                app.Expfilter = "t2";
            elseif app.BothMenu_2.Checked == "on"
               app.cdata = ffts(iffts(app.cdata,5).*permute(filte1,[3,4,5,1,2]),5);
                         app.cdata = ffts(iffts(app.cdata,4).*permute(filte2,[3,4,5,2,1]),4);
                app.Expfilter = "Both";
            else
                app.Expfilter = "No";
                app.ExpEditField.Enable = "off";
                app.ExpEditField.Value = 0;
            end
            info = strcat("Exponential filter: ", app.Expfilter, "  Gauss filter: ", app.Gaussfilter, "  K-Space filter: ", app.filter);
            app.infolabel.Text = info;
            disp("==========================");
            updatesliceview(app);
            updatespectrum(app);
        end


%             %use cdata to store filtered data
%             %%k-space - filters
%             if app.HanningMenu.Checked == "on"
%                 if app.filter ~= "Hann"
%                     % hanning
%                     app.filter = "Hann";
%                     filt = hanning(app.ny).*hanning(app.nx).'.*permute(hanning(app.nz),[2,3,1]);
%                     app.cdata = ifftsn(ifftsn(ifftsn(fftsn(fftsn(fftsn(app.wsdata,1),2),3).*filt ,1),2),3);
% %                     disp('Hanning applied');
%                 end
%             elseif app.HammingMenu.Checked == "on"
%                 if app.filter ~= "Hamm"
%                     % hamming
%                    % define filter for three spatial dimensions
%                     filt = hamming(app.ny).*hamming(app.nx).'.*permute(hamming(app.nz),[2,3,1]);
%                     app.filter = "Hamm"
%                     %tranform to fourier domain, apply filter and then transform back to image domain
%                     app.cdata = ifftsn(ifftsn(ifftsn(fftsn(fftsn(fftsn(app.wsdata,1),2),3).*filt ,1),2),3); 
% %                     disp('Hamming applied');
%                 end
%             else
%                 %how do you clear to default no filt but tilt in place
%                 if app.filter ~= "No"
%                     %====reverse======%
%                     app.cdata = app.wsdata;
%                     app.filter = "No";
% %                     disp('No filter applied')
%                 end
%             end
%             
%             %% Gauss filters
%             filt1 = exp(-(pi*app.gauslw*([0:app.nt1-1]-app.nt1/2)/app.bw1).^2/(2*log(2))); % 1x64
%             filt2 = exp(-(pi*app.gauslw*([0:app.nt2-1]/app.bw2)).^2/(2*log(2)));  % 1x512
%             if app.t1Menu.Checked == "on"
%                 if app.Gaussfilter ~= "t1"
%                     if app.Gaussfilter == "Both"
%                         app.cdata = app.cdata.*permute(1./filt2,[3,4,5,2,1]);
%                     else
%                         app.cdata = app.cdata.*permute(filt1,[3,4,5,1,2]);
%                     end
%                 app.Gaussfilter = "t1";
%                 end
% %                 disp('Gaussian applied in t1');
%             elseif app.t2Menu.Checked == "on"
%                 if app.Gaussfilter ~= "t2"
%                     if app.Gaussfilter == "Both"
%                         %app.cdata = iffts(iffts(ffts(ffts(app.wsdata,4),5)./filt1 ,4) ,5);
%                         app.cdata = app.cdata.*permute(1./filt1,[3,4,5,1,2]);
%                     else
%                         app.cdata = ffts(iffts(app.cdata,4).*permute(filt2,[3,4,5,2,1]),4);
%                     end
%                 app.Gaussfilter = "t2";
%                 end
% %                 disp('Gaussian applied in t2');
%             elseif app.BothMenu.Checked == "on"
%                 if app.Gaussfilter ~= "Both"
%                     if app.Gaussfilter == "t2"
%                         app.cdata = ffts(iffts(app.cdata,5).*permute(filt1,[3,4,5,1,2]),5);
%                     elseif app.Gaussfilter == "t1"
%                         app.cdata = ffts(iffts(app.cdata,4).*permute(filt2,[3,4,5,2,1]),4);
%                     else
%                         app.cdata = ffts(iffts(app.cdata,5).*permute(filt1,[3,4,5,1,2]),5);
%                         app.cdata = ffts(iffts(app.cdata,4).*permute(filt2,[3,4,5,2,1]),4);
%                     end
%                 app.Gaussfilter = "Both";
%                 end
% %                 disp('Gaussian applied in both t1 and t2');
%             else
%                 if app.Gaussfilter ~= "No"
%                     if app.Gaussfilter == "t2"
%                         app.cdata = ffts(iffts(app.cdata,4).*permute(1./filt2,[3,4,5,2,1]),4);
% 
%                     elseif app.Gaussfilter == "t1"
%                         app.cdata = ffts(iffts(app.cdata,5).*permute(1./filt1,[3,4,5,1,2]),5);
% 
%                     else
%                         app.cdata = ffts(iffts(app.cdata,4).*permute(1./filt2,[3,4,5,2,1]),4);
%                         app.cdata = ffts(iffts(app.cdata,5).*permute(1./filt1,[3,4,5,1,2]),5);
%                     end
%                 app.Gaussfilter = "No";
%                  end  
% %                 disp('Gaussian not applied');
%             end
% 
%             %% Exponential filters
%             filte1 = exp(-pi*app.explw*abs(([0:app.nt1-1]-app.nt1/2))/app.bw1);
%             filte2 = exp(-pi*app.explw*abs([0:app.nt2-1]/app.bw2));
%             if app.t1Menu_2.Checked == "on"
%                 if app.Expfilter ~= "t1"
%                     if app.Expfilter == "both"
%                          app.cdata = ffts(iffts(app.cdata,4).*permute(1./filte2,[3,4,5,2,1]),4);
%                     else
%                          app.cdata = ffts(iffts(app.cdata,5).*permute(filte1,[3,4,5,1,2]),5);
%                     end
%                 app.Expfilter = "t1";
%                  end
% %                 disp('Exponential applied in t1');
%             elseif app.t2Menu_2.Checked == "on"
%                 if app.Expfilter ~= "t2"
%                     if app.Expfilter == "Both"
%                         app.cdata = ffts(iffts(app.cdata,5).*permute(1./filte1,[3,4,5,1,2]),5);
%                     else
%                          app.cdata = ffts(iffts(app.cdata,4).*permute(filte2,[3,4,5,2,1]),4);
%                     end
%                 app.Expfilter = "t2";
%                 end
%                  app.Expfilter = "t2";
% %                 disp('Exponential applied in t2');
%             elseif app.BothMenu_2.Checked == "on"
%                 if app.Expfilter ~= "Both"
%                     if app.Expfilter == "t2"
%                          app.cdata = ffts(iffts(app.cdata,5).*permute(filte1,[3,4,5,1,2]),5);
%                     elseif app.Expfilter == "t1"
%                          app.cdata = ffts(iffts(app.cdata,4).*permute(filte2,[3,4,5,2,1]),4);
%                     else
%                          app.cdata = ffts(iffts(app.cdata,5).*permute(filte1,[3,4,5,1,2]),5);
%                          app.cdata = ffts(iffts(app.cdata,4).*permute(filte2,[3,4,5,2,1]),4);
%                     end
%                 app.Expfilter = "Both";
%                  end
% %                 disp('Exponential applied in both t1 and t2');
%             else
%                 if app.Expfilter ~= "No"
%                     if app.Expfilter == "t2"
%                          app.cdata = ffts(iffts(app.cdata,4).*permute(1./filte2,[3,4,5,2,1]),4);
%                     elseif app.Expfilter == "t1"
%                         app.cdata = ffts(iffts(app.cdata,5).*permute(1./filte1,[3,4,5,1,2]),5);
%                     else
%                         app.cdata = ffts(iffts(app.cdata,5).*permute(1./filte1,[3,4,5,1,2]),5);
%                         app.cdata = ffts(iffts(app.cdata,4).*permute(1./filte2,[3,4,5,2,1]),4);         
%                     end
%                 app.Expfilter = "No";
%                 end  
% %                 disp('Exponential not applied');
%             end
%             info = strcat("Exponential filter: ", app.Expfilter, "  Gauss filter: ", app.Gaussfilter, "  K-Space filter: ", app.filter);
%             app.infolabel.Text = info;
%             disp("==========================");
%             updatesliceview(app);
%             updatespectrum(app);
%         end

        function updateclines(app,cscalein,nlines,lmodein)
            app.clinenum                  = nlines;
            app.ContourLinesSpinner.Value = nlines;
            app.cscale                    = cscalein;
            app.lmode                     = lmodein;
            if app.lmode == 1
                app.clines = @(scale, clinenum) rescale(1-exp(-[1:clinenum]/app.cscale),scale(1),scale(2));
            elseif app.lmode == 2
                app.clines = @(scale, clinenum) rescale(exp([1:clinenum]/app.cscale),scale(1),scale(2));
            elseif app.lmode == 3
                app.clines = @(scale, clinenum) rescale(log(1:clinenum),scale(1),scale(2));
            end
            updatespectrum(app);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, recon)
            
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            app.UIFigure.Visible = 'on';

            onoff(app,'off','off');
            app.SpectrumviewButtonGroup.Enable = 'off';
            app.SelectSlicesPanel.Visible      = 'off';
            app.adjustcontourlevelsButton.Enable = 'off';
            app.ColormapDropDown.Items         = app.cmaps(1:end-1);
            app.ColormapDropDown_2.Items       = app.cmaps;
            app.ColormapDropDown_2.Value       = app.cmaps{3};
            app.MetMapDropDown.Items           = app.keySet;
            app.MetMapDropDown.Items{end+1}    = 'add new';
            app.WhereisDropDown.Items          = {'NAA?','Cre3.0?','Cho?','Other'};

            app.zeroMenu.Checked = "on";
            app.NoFilterMenu.Checked = "on";
            app.NoneMenu.Checked = "on";
            app.t1Menu.Checked = "off";
            app.NoneMenu_2.Checked = "on";
            % Colormap with black in the middle
            r = [1 0 0.00 0 0.75 1 1];
            g = [1 0 0.75 0 0.75 0 1];
            b = r(end:-1:1);
            x = linspace(1,size(get(app.UIFigure,'colormap'),1),7);
            app.c_dark = interp1(x,[r;g;b].',1:size(get(app.UIFigure,'colormap'),1),'linear');

            if exist('recon','var')
                if ndims(recon)==4, recon                  = permute(recon,[1,2,5,3,4]); end   
                [app.ny, app.nx, app.nz, app.nt2, app.nt1] = size(recon);
                app.wsed = zeros([app.nx, app.ny, app.nz]);
                app.cdata      = app.recon; 
                app.wsdata     = app.cdata;
                onoff(app,'on','off');
                app.SpectrumviewButtonGroup.Enable = 'on';
                if app.SinglevoxelcontourButton.Value
                    app.adjustcontourlevelsButton.Enable = 'on';
                end
                app.recon = recon;
                clear recon
                populatefields(app);
                updateapp(app);
            end

            app.RangeppmEditField.Editable      = 'off';
            app.toEditField_5.Editable          = 'off';
            app.AddButton.Enable                = 'off';
            app.AddButton.Visible               = 'off';
            app.EnterNameEditField.Enable       = 'off';
            app.EnterNameEditField.Visible      = 'off';
            app.EnterNameEditFieldLabel.Visible = 'off';
        end

        % Callback function: MetMapDropDown, ScalewrtSpinner, SliceSpinner, 
        % SliceviewButtonGroup
        function SliceComponentValueChanged(app, event)
            if strcmpi(app.MetMapDropDown.Value,'add new')
                app.AddButton.Enable                = 'on';
                app.AddButton.Visible               = 'on';
                app.EnterNameEditField.Enable       = 'on';
                app.EnterNameEditField.Visible      = 'on';
                app.EnterNameEditFieldLabel.Visible = 'on';
                app.EnterNameEditField.Value        = 'new name';
                app.RangeppmEditField.Editable      = 'on';
                app.toEditField_5.Editable          = 'on';
            else
                temp                                = app.M(app.MetMapDropDown.Value);
                app.RangeppmEditField.Value         = temp(1);
                app.toEditField_5.Value             = temp(2);
                app.AddButton.Enable                = 'off';
                app.AddButton.Visible               = 'off';
                app.EnterNameEditField.Enable       = 'off';
                app.EnterNameEditField.Visible      = 'off';
                app.EnterNameEditFieldLabel.Visible = 'off';
                app.RangeppmEditField.Editable      = 'off';
                app.toEditField_5.Editable          = 'off';

                updatesliceview(app);
            end
        end

        % Callback function
        function DefaultButtonPushed(app, event)
            %reverse to default parameter sttings
            app.s2       = app.s2ref;
            app.s1       = app.s1ref;
            app.lw       = app.lwref;
            app.cdata    = app.wsdata;  
            app.cmin2    = app.rmin2;
            app.cmax2    = app.rmax2;
            app.clinenum = app.clinenumref; 
            app.cscale   = app.cscaleref;
            app.lmode    = app.lmoderef;
            app.fill     = 'Off';
            app.bg       = 'black';
            
            app.f1range  = [-50 50];
            app.f2range  = [1.5 4.5];
            app.bg       = 'black';
            
            populatefields(app);
            updateapp(app);
        end

        % Callback function
        function SaveButtonPushed(app, event)
            %save a series of parameters for plot viewing
            [file,path] = uiputfile('*.mat','Save the parameter file!');
            cd(path);
            noise       = app.NoiseScaleSpinner.Value;
            threshold   = app.ScalewrtSpinner.Value;
            signal      = app.SignalScaleSpinner.Value;
            cfill       = app.ContourLineFillingSwitch.Value;
            ncline      = app.ContourLinesSpinner.Value;
            tcline      = app.LinethicknessSpinner.Value;
            scalewrt    = app.ScalewrtSpinner.Value;
            sliceidx    = app.SliceSpinner.Value; 
            bgcolor     = app.Button_2.BackgroundColor;
            f2range = [app.F2rangeEditField.Value, app.toEditField_4.Value];
            f1range = [app.F1rangeEditField.Value, app.toEditField_3.Value];
            
            eval(sprintf(...
                        'save %s noise threshold signal cfill ncline tcline scalewrt sliceidx bgcolor f2range f1range -v7.3',file));
        end

        % Callback function
        function LoadButtonPushed(app, event)
            %load saved parameters for plot viewing 
            uiopen('*.mat');
             app.NoiseScaleSpinner.Value = noise;
             app.ScalewrtSpinner.Value = threshold;
             app.SignalScaleSpinner.Value = signal;
             app.ContourLineFillingSwitch.Value = cfill;
             app.ContourLinesSpinner.Value = ncline;
             app.LinethicknessSpinner.Value = tcline;
             app.ScalewrtSpinner.Value = scalewrt;
             app.SliceSpinner.Value = sliceidx;
             app.bg = bgcolor;
             app.Button_2.BackgroundColor = app.bg;
             app.f2range(1) = app.F2rangeEditField.Value; 
             app.f2range(2) = app.toEditField_4.Value;
             app.f1range(1) = app.F1rangeEditField.Value;
             app.f1range(2) = app.toEditField_3.Value;

             updateapp(app);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            %save single-vox spec
            app.Label.Text = "Saving single-voxel spectrum";
            filename       = strcat(app.folder,"single-voxel_contour_rowc",num2str(app.ypos),"_col",num2str(app.xpos),'.tif');
            %fxc = 0;
            %while isfile(filename), fxc = fxc+1; filename = sprintf('%s_%d.tif',fname(1:end-4),fxc); end
            exportgraphics(app.UIAxes2,filename,'Resolution',300);
            app.Label.Text = 'Single-voxel spectrum saved';
        end

        % Button pushed function: PopoutButton
        function PopoutButtonPushed(app, event)
            %open single-vox spec in separate window
            copyUIAxes(app.UIAxes2);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            warning('off');
            close all;
            delete(app);
%             delete(app.multivoxel);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %spec background color selector
            try
                app.bg = uisetcolor;
                app.Button_2.BackgroundColor = app.bg;
                app.UIAxes2.Color            = app.bg;
                figure(app.UIFigure);
            catch
                app.bg = 'black';
                figure(app.UIFigure);
            end
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            %save all-slice view w.r.t. different met maps
            app.MultiuseLabel.Text  = '';
            %temporarily disable button while saving
            app.SaveButton_3.Enable = 'off';
            %saving 'NAA', 'Cho', 'Cre30', 'Cre39', 'Glx', 'mi'
            metdata = app.MetMapDropDown.Items;
            idxlist = [1,2,3,4,5,7]; 
            for i = idxlist
                app.MultiuseLabel.Text = strcat('Saving;',num2str(i),'/',num2str(length(idxlist)),'(',metdata{i},')');
                filename = strcat(app.folder,'metmap-',metdata{i},'.tif');
                disp(filename);
                %fxc = 0;
                %while isfile(filename), fxc = fxc+1; filename = strcat('all-slice-metmep-', metdata{i} ,num2str(fxc),'.tif'); end
                %disp(filename);
                app.MetMapDropDown.Value = i;
                app.AllButton.Value      = 1;
                updatesliceview(app);
                exportgraphics(app.UIAxes,filename,'Resolution',300);
            end
            app.MultiuseLabel.Text   = 'All saved.';
            %reverse the button/drop down states back to original
            app.SaveButton_3.Enable  = 'on';
            app.MetMapDropDown.Value = 1; 
        end

        % Callback function
        function PopoutButton_2Pushed(app, event)
            copyUIAxes(app.UIAxes);
        end

        % Button pushed function: AdjustButton
        function AdjustButtonPushed(app, event)
            adjust(app, app.F2ppmEditField.Value, app.F1HzEditField.Value);
            %update already called
        end

        % Menu selected function: ParametersMenu
        function ParametersMenuSelected(app, event)

            % Open the options dialog and pass inputs
%             app.OptionsApp = inputapp(app, app.bw1, app.bw2, app.zp1, app.zp2, app.T);
            inputapp(app, app.bw1, app.bw2, app.zp1, app.zp2, app.T);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %create a dummy figure so that uigetfile doesn't minimize our GUI
            f            = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); drawnow
            [file,path]  = uigetfile('*.mat'); %open a mat file
            figure(app.UIFigure);
            delete(f); % delete dummy
            
            if file
                app.filepath   = fullfile(path,file); 
                app.folder     = path;
    
                cla(app.UIAxes)
                cla(app.UIAxes2)
                drawnow

                datastruct      = load(app.filepath,'ws_reconcs');
                app.reconmethod = regexp(app.filepath,'(?<=recon).*(?=(.mat))','Match');
                app.recon       = datastruct.ws_reconcs;
                [app.ny, app.nx, app.nz, ~, ~] = size(app.recon);
                app.wsed = zeros([app.nx, app.ny, app.nz]);
                app.cdata = app.recon;
                app.wsdata = app.cdata;
                try
                    dsname = regexp(app.folder,'\d{14}','Match');
                    app.CurrentDS.Text = strcat("DS: ", dsname, " Recon: ", app.reconmethod);
                catch
                    app.CurrentDS.Text = strcat("DS: modified data");
                end
                if ndims(app.cdata)==4, app.cdata = permute(app.cdata,[1,2,5,3,4]); end   
                [app.ny, app.nx, app.nz, app.nt2, app.nt1]  = size(app.cdata);
                app.wsdata                                   = app.cdata;

                if app.SinglevoxelcontourButton.Value
                    app.adjustcontourlevelsButton.Enable = 'on';
                end
                onoff(app,'on','off');
                app.SpectrumviewButtonGroup.Enable = 'on';
                app.SelectSlicesPanel.Visible      = 'off';
                clear datastruct;
                populatefields(app);
                updateapp(app);
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.keySet{end+1}   = app.EnterNameEditField.Value;
            app.valueSet{end+1} = [app.RangeppmEditField.Value,app.toEditField_5.Value];
            app.M               = containers.Map(app.keySet,app.valueSet);
            app.MetMapDropDown.Items            = app.keySet;
            app.MetMapDropDown.Items{end+1}     = 'add new';
            app.AddButton.Enable                = 'off';
            app.AddButton.Visible               = 'off';
            app.EnterNameEditField.Enable       = 'off';
            app.EnterNameEditField.Visible      = 'off';
            app.EnterNameEditFieldLabel.Visible = 'off';
            app.RangeppmEditField.Editable      = 'off';
            app.toEditField_5.Editable          = 'off';
            app.MetMapDropDown.Value            = app.keySet{end};
            updatesliceview(app)
        end

        % Value changed function: WhereisDropDown
        function WhereisDropDownValueChanged(app, event)
             app.F2AdjustField.Editable = 'off';
             app.F1AdjustField.Editable = 'off';
            if strcmpi(app.WhereisDropDown.Value,'NAA?')
                app.F2AdjustField.Value    = 2.01;
                app.F1AdjustField.Value           = 0;
            elseif strcmpi(app.WhereisDropDown.Value,'Cre3.0?')
                app.F2AdjustField.Value    = 3.03;
                app.F1AdjustField.Value           = 0;
            elseif strcmpi(app.WhereisDropDown.Value,'Cho?')
                app.F2AdjustField.Value    = 3.19;
                app.F1AdjustField.Value           = 0;
            elseif strcmpi(app.WhereisDropDown.Value,'Other')
                app.F2AdjustField.Editable = 'on';
                app.F1AdjustField.Editable  = 'on';
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            updatespectrum(app);
        end

        % Selection changed function: SpectrumviewButtonGroup
        function SpectrumviewButtonGroupSelectionChanged(app, event)
            if app.SinglevoxelcontourButton.Value || app.SinglevoxelintensityButton.Value
                %enable/disable button/fields
                onoff(app,'on','off');
                app.SelectSlicesPanel.Visible = 'off';
                updatespectrum(app);
            elseif app.MultivoxelcontourButton.Value
                onoff(app,'off','on');
                app.SelectSlicesPanel.Visible = 'on';
                expand(app.Tree);
                %pass down ROI coords
                app.xtop    = app.ColumnrangeEditField.Value;
                app.xbottom = app.toEditField_2.Value;
                app.ytop    = app.RowrangeEditField.Value;
                app.ybottom = app.toEditField.Value;
            end
            if app.SinglevoxelcontourButton.Value
                app.adjustcontourlevelsButton.Enable = 'on';
            else
                app.adjustcontourlevelsButton.Enable = 'off';
            end
            if app.SinglevoxelintensityButton.Value
                app.MultivoxelcontourButton.Enable = 'off';
            else
                app.MultivoxelcontourButton.Enable = 'on';
            end
        end

        % Value changed function: SignalScaleSpinner
        function SignalScaleSpinnerValueChanged(app, event)
            app.s2    = app.s2ref - app.SignalScaleSpinner.Value * 0.1;
            updatespectrum(app);
        end

        % Value changed function: NoiseScaleSpinner
        function NoiseScaleSpinnerValueChanged(app, event)
            app.s1    = app.s1ref - app.NoiseScaleSpinner.Value * 0.1;
            updatespectrum(app);
        end

        % Value changed function: ContourLinesSpinner
        function ContourLinesSpinnerValueChanged(app, event)
            app.clinenum = app.ContourLinesSpinner.Value;
            updatespectrum(app);
        end

        % Value changed function: LinethicknessSpinner
        function LinethicknessSpinnerValueChanged(app, event)
            app.lw = app.LinethicknessSpinner.Value; %countourline thickness
            updatespectrum(app);
        end

        % Value changed function: ContourLineFillingSwitch
        function ContourLineFillingSwitchValueChanged(app, event)
            app.fill = app.ContourLineFillingSwitch.Value;
            updatespectrum(app);
        end

        % Value changed function: ColormapDropDown_2
        function ColormapDropDown_2ValueChanged(app, event)
%             value = app.ColormapDropDown_2.Value;
            updatespectrum(app);
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
%             value = app.ColormapDropDown.Value;
            updatesliceview(app);
        end

        % Value changed function: F1rangeEditField
        function F1rangeEditFieldValueChanged(app, event)
            app.f1range(1) = app.F1rangeEditField.Value;
            preparerange(app);
            updatespectrum(app);
        end

        % Value changed function: toEditField_3
        function toEditField_3ValueChanged(app, event)
            app.f1range(2) = app.toEditField_3.Value;
            preparerange(app);
            updatespectrum(app);
        end

        % Value changed function: F2rangeEditField
        function F2rangeEditFieldValueChanged(app, event)
            app.f2range(1) = app.F2rangeEditField.Value;
            preparerange(app);
            updatespectrum(app);
        end

        % Value changed function: toEditField_4
        function toEditField_4ValueChanged(app, event)
            app.f2range(2) = app.toEditField_4.Value;
            preparerange(app);
            updatespectrum(app);
        end

        % Button pushed function: PopoutButton_2
        function PopoutButton_2Pushed2(app, event)
            %open single-vox spec in separate window
            copyUIAxes(app.UIAxes);
        end

        % Button pushed function: SaveButton_4
        function SaveButton_4Pushed(app, event)
            %save single-vox spec
            app.Label.Text = "Saving metabolite map";
            if app.AllButton.Value
                filename = strcat(app.folder,"metmap_",app.MetMapDropDown.Value,"_ALLslices",'.tif');
            elseif app.SingleButton.Value
                filename = strcat(app.folder,"metmap_",app.MetMapDropDown.Value,"_slice",num2str(app.SliceSpinner.Value),'.tif');
            end
            %fxc = 0;
            %while isfile(filename), fxc = fxc+1; filename = sprintf('%s_%d.tif',fname(1:end-4),fxc); end
            exportgraphics(app.UIAxes,filename,'Resolution',300);
            app.Label.Text = 'Metmap saved';
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            app.SpectrumviewButtonGroup.Enable = 'off';
            app.SelectSlicesPanel.Enable       = 'off';
            
            try
                filename = strcat('output-multivoxel.pdf');
                fxc = 0;
                while isfile(filename), fxc = fxc+1; filename = strcat('output-multivoxel',num2str(fxc),'.pdf'); end              
                nodes = app.Tree.CheckedNodes;
                for i = 1:length(nodes)
                    app.SelectSlicesPanel.Title = strcat('Generating slice#',num2str(nodes(i).NodeData),' ...');
                    drawslice(app,nodes(i).NodeData,false);
    %                 if ~ishandle(nodes(i).NodeData+100)
    %                     drawslice(app,nodes(i).NodeData,false);
    %                 else
    %                     figure(nodes(i).NodeData+100);
    %                 end
                    app.SelectSlicesPanel.Title = strcat(num2str(nodes(i).NodeData), ' generated.');
                
                end
                app.SelectSlicesPanel.Title        = strcat('All slices generated.');
            catch
            end
            
            app.SpectrumviewButtonGroup.Enable = 'on';
            app.SelectSlicesPanel.Enable       = 'on';
            figure(app.UIFigure);
        end

        % Button pushed function: SaveButton_5
        function SaveButton_5Pushed(app, event)
            app.SpectrumviewButtonGroup.Enable = 'off';
            app.SelectSlicesPanel.Enable       = 'off';

            import mlreportgen.ppt.*
%             app.Label.Text = app.paramlist;

            %save selected slices
            if app.TIFButton.Value
                saveopt = 3;
            elseif app.PPTButton.Value
                saveopt = 2; 
            else
                saveopt = 1;
            end
            nodes = app.Tree.CheckedNodes;
            switch(saveopt)
                case 1 %pdf
                    filename = 'output-multivoxel.pdf';
                    %deal with duplicated files
                    fxc = 0;
                    while isfile(filename), fxc = fxc+1; filename = strcat('output-multivoxel',num2str(fxc),'.pdf'); end 
%                     app.SelectslicesLabel.Text = '';
                    for i = 1:length(nodes)
                        sl = nodes(i).NodeData; %slice index
                        idx = sl+100;
                        app.SelectSlicesPanel.Title = strcat('Saving slice#',num2str(nodes(i).NodeData),' ...');
                        %save to pdf
                        if ~ishandle(idx)
                            %generate the slice if non-existent
                            f = drawslice(app,nodes(i).NodeData,false);
                            exportgraphics(f, filename, 'Append', true,'Resolution',300);
                        else
                            %focus
                            figure(idx);
                            exportgraphics(gcf, filename, 'Append', true,'Resolution',300);
                        end
                       app.SelectSlicesPanel.Title = strcat(num2str(nodes(i).NodeData), 'saved.');
                    end

                %ppt
                case 2 
                    %filepath
                    templatepath = 'C:\Users\athomas\Documents\MATLAB\Merlin_codes\powerpoints\generate-template.pptx';
                    ds           = regexp(app.folder,'\d{14}','Match');
                    if numel(ds)<1, ds{1} = 'results'; end
                    savepath = strcat(app.folder,ds{1},'-',app.reconmethod,'-output-multivoxel');
                    filename = strcat(savepath,'.pptx');
                    %deal with duplicated files
                    fxc = 0;
                    while isfile(filename), fxc = fxc+1; filename = strcat(savepath,num2str(fxc),'.pptx'); end 
                    makePPTCompilable();
                    disp(filename{1});
                    %create ppt, choose template
                    slides = mlreportgen.ppt.Presentation(filename{1}, templatepath);
                    %specify dataset and recon method in the coverslide
                    titleSlide = add(slides,'cover');
                    replace(titleSlide,'dataset',ds);
                    replace(titleSlide,'recon method', app.reconmethod);
                    %info slices, cropped 
                    info1 = add(slides, 'info');
                    im1 = imread(fullfile(app.folder, strcat(ds,'_5DEPJRESI_loc.png')));
                    crop1 = imcrop(im1, [0 (1/2*height(im1) - 100) 1/2*width(im1) height(im1)]);
                    imwrite(crop1,'i1.png');
                    i1 = mlreportgen.ppt.Picture('i1.png');
                    replace(info1, 'loginfo', i1);
                    im2 = imread(fullfile(app.folder, strcat(ds,'_5DEPJRESI_shim.png')));
                    crop2 = imcrop(im2, [0 (1/2*height(im2) - 100) 1/2*width(im2) height(im2)]);
                    imwrite(crop2,'i2.png');
                    i2 = mlreportgen.ppt.Picture('i2.png');
                    replace(info1, 'shiminfo', i2);
                    %both i1 and i2.png are temp and won't be saved in the dataset folder,
                    
%                     app.SelectslicesLabel.Text = '';
                    for i = 1:length(nodes)
                        sl = nodes(i).NodeData; %slice index
                        idx = sl+100;
                        app.SelectSlicesPanel.Title = strcat('Saving slice#',num2str(sl),' ...');
                        picslide = add(slides, 'multi');
                        %replace(picslide, 'Title', strcat('slide#',num2str(sl)));
                        fname = strcat(app.folder,'multivoxel-slice-',num2str(sl));
                        filename = strcat(fname,'.tif');
                        %deal with duplicated files
                        fxc = 0;
                        while isfile(filename), fxc = fxc+1; filename = strcat(fname,'(',num2str(fxc),')','.tif'); end        
                        if ~ishandle(idx)
                            %generate the slice if non-existent
                            f = drawslice(app,sl,app.rows,app.clms,true);
                            exportgraphics(f, filename, 'Resolution',300);
                        else
                            %focus
                            figure(idx);
                            exportgraphics(gcf, filename,'Resolution',300);
                        end
                        pic = mlreportgen.ppt.Picture(filename);
                        replace(picslide, 'multivoxel', pic);
                        app.SelectSlicesPanel.Title = strcat(num2str(nodes(i).NodeData), 'saved.');
                    end

                    %attaching the metaobolite map views
                    for i = [1,2,3,4,5,7] %length(metdata) saving the selected four
                        metdata = {'NAA', 'Cho', 'Cre30', 'Cre39', 'Glx', 'lac', 'mi', 'water', 'fat'};
                        fn = strcat('metmap-',metdata{i});
                        filename = strcat(fn,'.tif');
                        picslide = add(slides, 'metmap');
                        pic = mlreportgen.ppt.Picture(filename);
                        replace(picslide, 'metmap', pic);
                        replace(picslide, 'metmaptitle', fn);
                    end
                    close(slides);
                    rptview(slides);

                    %tif
                case 3
                    for i = 1:length(nodes)
                        sl = nodes(i).NodeData; %slice index
                        idx = sl+100;
                        app.SelectSlicesPanel.Title = strcat('Saving slice#',num2str(sl),' ...');
                        fname = strcat('multivoxel-slice-',num2str(sl));
                        filename = strcat(fname,'.tif');
                        %deal with duplicated files
                        fxc = 0;
                        while isfile(filename), fxc = fxc+1; filename = strcat(fname,'(',num2str(fxc),')','.tif'); end 
                        
                        if ~ishandle(idx)
                            %generate the slice if non-existent
                            f = drawslice(app,sl,app.rows,app.clms,true);
                            exportgraphics(f, filename, 'Resolution',300);
                        else
                            %focus
                            figure(idx);
                            exportgraphics(gcf, filename, 'Resolution',300);
                        end
                       app.SelectSlicesPanel.Title = strcat(num2str(nodes(i).NodeData), 'saved.');
                    end
            end
            %resume button functions
            app.SelectSlicesPanel.Title        = strcat('All slices saved.'); 
            app.SpectrumviewButtonGroup.Enable = 'on';
            app.SelectSlicesPanel.Enable       = 'on';
            figure(app.UIFigure);
        end

        % Button pushed function: adjustcontourlevelsButton
        function adjustcontourlevelsButtonPushed(app, event)
            % Disable button while dialog is open
            app.adjustcontourlevelsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = contourlevelplot(app,app.clinenum,app.cscale,app.lmode);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %save a series of parameters for plot viewing
            [file,path] = uiputfile('*.mat','Save the parameter file!');
            cd(path);
            noise       = app.NoiseScaleSpinner.Value;
            threshold   = app.ScalewrtSpinner.Value;
            signal      = app.SignalScaleSpinner.Value;
            cfill       = app.ContourLineFillingSwitch.Value;
            ncline      = app.ContourLinesSpinner.Value;
            tcline      = app.LinethicknessSpinner.Value;
            scalewrt    = app.ScalewrtSpinner.Value;
            sliceidx    = app.SliceSpinner.Value; 
            bgcolor     = app.Button_2.BackgroundColor;
            f2range = [app.F2rangeEditField.Value, app.toEditField_4.Value];
            f1range = [app.F1rangeEditField.Value, app.toEditField_3.Value];
            
            eval(sprintf(...
                        'save %s noise threshold signal cfill ncline tcline scalewrt sliceidx bgcolor f2range f1range -v7.3',file));
       

        % Button pushed function: LoadButton
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
              %load saved parameters for plot viewing 
             uiopen('*.mat');
             app.NoiseScaleSpinner.Value = noise;
             app.ScalewrtSpinner.Value = threshold;
             app.SignalScaleSpinner.Value = signal;
             app.ContourLineFillingSwitch.Value = cfill;
             app.ContourLinesSpinner.Value = ncline;
             app.LinethicknessSpinner.Value = tcline;
             app.ScalewrtSpinner.Value = scalewrt;
             app.SliceSpinner.Value = sliceidx;
             app.bg = bgcolor;
             app.Button_2.BackgroundColor = app.bg;
             app.f2range(1) = app.F2rangeEditField.Value; 
             app.f2range(2) = app.toEditField_4.Value;
             app.f1range(1) = app.F1rangeEditField.Value;
             app.f1range(2) = app.toEditField_3.Value;
             updatespectrum(app);
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
               %reverse to default parameter sttings
            app.s2       = app.s2ref;
            app.s1       = app.s1ref;
            app.lw       = app.lwref;
            app.cdata    = app.wsdata;  
            app.cmin2    = app.rmin2;
            app.cmax2    = app.rmax2;
            app.clinenum = app.clinenumref; 
            app.cscale   = app.cscaleref;
            app.lmode    = app.lmoderef;
            app.fill     = 'Off';
            app.bg       = 'black';
            
            app.f1range  = [-50 50];
            app.f2range  = [1.5 4.5];
            app.bg       = 'black';

            app.filter = "No";
            app.tilt = 0;
            
            populatefields(app);
            updatespectrum(app);
        end

        % Menu selected function: HanningMenu
        function HanningMenuSelected(app, event)
                app.HanningMenu.Checked = "on";
                app.HammingMenu.Checked = "off";
                app.NoFilterMenu.Checked = "off";
                updateapp(app);
%                % hanning
%                 filt = hanning(app.ny).*hanning(app.nx).'.*permute(hanning(app.nz),[2,3,1]);
%                 app.cdata = ifftsn(ifftsn(ifftsn(fftsn(fftsn(fftsn(app.wsdata,1),2),3).*filt ,1),2),3);
%                 updateapp(app);
%                 disp('Hanning applied');
        end

        % Menu selected function: HammingMenu
        function HammingMenuSelected(app, event)
                app.HanningMenu.Checked = "off";
                app.HammingMenu.Checked = "on";
                app.NoFilterMenu.Checked = "off";
                updateapp(app);
%                % hamming
%                % define filter for three spatial dimensions
%                 filt = hamming(app.ny).*hamming(app.nx).'.*permute(hamming(app.nz),[2,3,1]);
%                 %tranform to fourier domain, apply filter and then transform back to image domain
%                 app.cdata = ifftsn(ifftsn(ifftsn( fftsn(fftsn(fftsn(app.wsdata,1),2),3).*filt ,1),2),3); 
%                 updateapp(app);
%                 disp('Hamming applied');
        end

        % Menu selected function: plusMenu
        function plusMenuSelected(app, event)
            app.plusMenu.Checked = "on";
            app.minusMenu.Checked = "off";
            app.zeroMenu.Checked = "off";
            updateapp(app);
            
%             app.cdata =  tiltcorrect5d(app.wsdata,2,840);
%             updateapp(app);
%             disp('tilt corrected (+2)');
        end

        % Menu selected function: minusMenu
        function minusMenuSelected(app, event)
            app.plusMenu.Checked = "off";
            app.minusMenu.Checked = "on";
            app.zeroMenu.Checked = "off";
            updateapp(app);
%             app.cdata =  tiltcorrect5d(app.wsdata,-2,840);
%             updateapp(app);
%             disp('tilt corrected (-2)');
        end

        % Menu selected function: zeroMenu
        function zeroMenuSelected(app, event)
            app.plusMenu.Checked = "off";
            app.minusMenu.Checked = "off";
            app.zeroMenu.Checked = "on";
            updateapp(app);
%             app.cdata =  app.wsdata;
%             updateapp(app);
%             disp('original (rdata)');
        end

        % Menu selected function: NoFilterMenu
        function NoFilterMenuSelected(app, event)
            app.HanningMenu.Checked = "off";
            app.HammingMenu.Checked = "off";
            app.NoFilterMenu.Checked = "on";
            updateapp(app);
%             app.cdata =  app.wsdata;
%             updateapp(app);
%             disp('original (rdata)');
        end

        % Button pushed function: wsApplyButton
        function wsApplyButtonPushed(app, event)
            %water suppression code
            %TILT CORRECT WITH FILTER FIRST!!!!!!!!!!
            [out1] = tiltcorrect5d(app.wsdata,1,840); % untilt the data
            disp(app.WsRowField1.Value:app.WsRowField2.Value);
            for r = app.WsRowField1.Value:app.WsRowField2.Value
                for c = app.WsColField1.Value:app.WsColField2.Value
                    for s = app.WsSliceField1.Value:app.WsSliceField2.Value
                        if app.wsed(r,c,s) ~= 1
                            disp(strcat("WS applying to ",num2str(r)," ",num2str(c)," ",num2str(s)));
                            [out1] = AJwrapper_peak_suppression(out1,0,80,1190,20,r,c,s); % removes water
                            app.wsed(r,c,s) = 1;
                        end
                    end
                end
            end
            app.wsdata = tiltcorrect5d(out1,-1,840); 
            app.cdata = app.wsdata;
            app.filter = "No";
            updateapp(app);
        end

        % Button pushed function: wsRevertAllButton
        function wsRevertAllButtonPushed(app, event)
            app.wsdata = app.recon;
            app.cdata = app.wsdata;
            app.tilt = 0;
            app.filter = "No"; 
            app.wsed = zeros([app.nx, app.ny,app.nz]);
            updateapp(app);
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            % Open the options dialog and pass inputs
            app.ExportApp = exportapp(app);
        end

        % Menu selected function: t1Menu
        function t1MenuSelected(app, event)
            app.t1Menu.Checked = "on";
            app.t2Menu.Checked = "off";
            app.NoneMenu.Checked = "off";
            app.BothMenu.Checked = "off";
            updateapp(app);
        end

        % Menu selected function: t2Menu
        function t2MenuSelected(app, event)
            app.t1Menu.Checked = "off";
            app.t2Menu.Checked = "on";
            app.NoneMenu.Checked = "off";
            app.BothMenu.Checked = "off";
            updateapp(app);
        end

        % Menu selected function: BothMenu
        function BothMenuSelected(app, event)
             app.t1Menu.Checked = "off";
            app.t2Menu.Checked = "off";
            app.NoneMenu.Checked = "off";
            app.BothMenu.Checked = "on";
            updateapp(app);
        end

        % Menu selected function: NoneMenu
        function NoneMenuSelected(app, event)
             app.t1Menu.Checked = "off";
            app.t2Menu.Checked = "off";
            app.NoneMenu.Checked = "on";
            app.BothMenu.Checked = "off";
            updateapp(app);
        end

        % Menu selected function: t1Menu_2
        function t1Menu_2Selected(app, event)
             app.t1Menu_2.Checked = "on";
            app.t2Menu_2.Checked = "off";
            app.NoneMenu_2.Checked = "off";
            app.BothMenu_2.Checked = "off";
            updateapp(app);
        end

        % Menu selected function: t2Menu_2
        function t2Menu_2Selected(app, event)
            app.t1Menu_2.Checked = "off";
            app.t2Menu_2.Checked = "on";
            app.NoneMenu_2.Checked = "off";
            app.BothMenu_2.Checked = "off";
            updateapp(app);
        end

        % Menu selected function: BothMenu_2
        function BothMenu_2Selected(app, event)
            app.t1Menu_2.Checked = "off";
            app.t2Menu_2.Checked = "off";
            app.NoneMenu_2.Checked = "off";
            app.BothMenu_2.Checked = "on";
            updateapp(app);
        end

        % Menu selected function: NoneMenu_2
        function NoneMenu_2Selected(app, event)
            app.t1Menu_2.Checked = "off";
            app.t2Menu_2.Checked = "off";
            app.NoneMenu_2.Checked = "on";
            app.BothMenu_2.Checked = "off";
            updateapp(app);
        end

        % Value changed function: GaussEditField
        function GaussEditFieldValueChanged(app, event)
           app.gauslw = app.GaussEditField.Value;
           app.Gaussfilter = "None";
           updateapp(app);
        end

        % Value changed function: ExpEditField
        function ExpEditFieldValueChanged(app, event)
           app.explw = app.ExpEditField.Value;
           app.Expfilter = "None";
           updateapp(app);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File ';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ParametersMenu = uimenu(app.FileMenu);
            app.ParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ParametersMenuSelected, true);
            app.ParametersMenu.Text = 'Parameters';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Text = 'Export';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit ';
            app.FilterMenu_2 = uimenu(app.EditMenu);
            app.FilterMenu_2.Text = 'Filter';
            app.kspaceMenu = uimenu(app.FilterMenu_2);
            app.kspaceMenu.Text = 'k-space';
            app.HanningMenu = uimenu(app.kspaceMenu);
            app.HanningMenu.MenuSelectedFcn = createCallbackFcn(app, @HanningMenuSelected, true);
            app.HanningMenu.Text = 'Hanning';
            app.HammingMenu = uimenu(app.kspaceMenu);
            app.HammingMenu.MenuSelectedFcn = createCallbackFcn(app, @HammingMenuSelected, true);
            app.HammingMenu.Text = 'Hamming';
            app.NoFilterMenu = uimenu(app.kspaceMenu);
            app.NoFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @NoFilterMenuSelected, true);
            app.NoFilterMenu.Text = 'No';
            app.GuassianMenu = uimenu(app.FilterMenu_2);
            app.GuassianMenu.Text = 'Guassian';
            app.t1Menu = uimenu(app.GuassianMenu);
            app.t1Menu.MenuSelectedFcn = createCallbackFcn(app, @t1MenuSelected, true);
            app.t1Menu.Text = 't1';
            app.t2Menu = uimenu(app.GuassianMenu);
            app.t2Menu.MenuSelectedFcn = createCallbackFcn(app, @t2MenuSelected, true);
            app.t2Menu.Text = 't2';
            app.BothMenu = uimenu(app.GuassianMenu);
            app.BothMenu.MenuSelectedFcn = createCallbackFcn(app, @BothMenuSelected, true);
            app.BothMenu.Text = 'Both';
            app.NoneMenu = uimenu(app.GuassianMenu);
            app.NoneMenu.MenuSelectedFcn = createCallbackFcn(app, @NoneMenuSelected, true);
            app.NoneMenu.Text = 'None';
            app.ExponentialMenu_3 = uimenu(app.FilterMenu_2);
            app.ExponentialMenu_3.Text = 'Exponential';
            app.t1Menu_2 = uimenu(app.ExponentialMenu_3);
            app.t1Menu_2.MenuSelectedFcn = createCallbackFcn(app, @t1Menu_2Selected, true);
            app.t1Menu_2.Text = 't1';
            app.t2Menu_2 = uimenu(app.ExponentialMenu_3);
            app.t2Menu_2.MenuSelectedFcn = createCallbackFcn(app, @t2Menu_2Selected, true);
            app.t2Menu_2.Text = 't2';
            app.BothMenu_2 = uimenu(app.ExponentialMenu_3);
            app.BothMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BothMenu_2Selected, true);
            app.BothMenu_2.Text = 'Both';
            app.NoneMenu_2 = uimenu(app.ExponentialMenu_3);
            app.NoneMenu_2.MenuSelectedFcn = createCallbackFcn(app, @NoneMenu_2Selected, true);
            app.NoneMenu_2.Text = 'None';
            app.ParametersMenu_2 = uimenu(app.EditMenu);
            app.ParametersMenu_2.Text = 'Parameters';
            app.SaveMenu = uimenu(app.ParametersMenu_2);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.ParametersMenu_2);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ResetMenu = uimenu(app.ParametersMenu_2);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.TiltcorrectionMenu = uimenu(app.EditMenu);
            app.TiltcorrectionMenu.Checked = 'on';
            app.TiltcorrectionMenu.Text = 'Tilt correction';
            app.plusMenu = uimenu(app.TiltcorrectionMenu);
            app.plusMenu.MenuSelectedFcn = createCallbackFcn(app, @plusMenuSelected, true);
            app.plusMenu.Text = '+';
            app.minusMenu = uimenu(app.TiltcorrectionMenu);
            app.minusMenu.MenuSelectedFcn = createCallbackFcn(app, @minusMenuSelected, true);
            app.minusMenu.Text = '-';
            app.zeroMenu = uimenu(app.TiltcorrectionMenu);
            app.zeroMenu.MenuSelectedFcn = createCallbackFcn(app, @zeroMenuSelected, true);
            app.zeroMenu.Text = '0';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {''; 'Slice View'})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.XTick = [0 2 4 6 8 10 12 14 16];
            app.UIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'};
            app.UIAxes.YTick = [0 2 4 6 8 10 12 14 16];
            app.UIAxes.YTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Spectrum View')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.SpectrumviewButtonGroup = uibuttongroup(app.UIFigure);
            app.SpectrumviewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpectrumviewButtonGroupSelectionChanged, true);
            app.SpectrumviewButtonGroup.Title = 'Spectrum view';
            app.SinglevoxelintensityButton = uiradiobutton(app.SpectrumviewButtonGroup);
            app.SinglevoxelintensityButton.Text = {'Single-voxel'; ' (intensity)'};
            app.SinglevoxelcontourButton = uiradiobutton(app.SpectrumviewButtonGroup);
            app.SinglevoxelcontourButton.Text = {'Single-voxel'; ' (contour)'};
            app.SinglevoxelcontourButton.Value = true;
            app.MultivoxelcontourButton = uiradiobutton(app.SpectrumviewButtonGroup);
            app.MultivoxelcontourButton.Text = {'Multi-voxel'; ' (contour)'};
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters ';
            app.SliceviewButtonGroup = uibuttongroup(app.UIFigure);
            app.SliceviewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SliceComponentValueChanged, true);
            app.SliceviewButtonGroup.Title = 'Slice view';
            app.SliceviewButtonGroup.Tag = 'SliceComponent';
            app.SingleButton = uiradiobutton(app.SliceviewButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.AllButton = uiradiobutton(app.SliceviewButtonGroup);
            app.AllButton.Text = 'All';
            app.Label = uilabel(app.UIFigure);
            app.SaveButton_2 = uibutton(app.UIFigure, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.PopoutButton = uibutton(app.UIFigure, 'push');
            app.PopoutButton.ButtonPushedFcn = createCallbackFcn(app, @PopoutButtonPushed, true);
            app.PopoutButton.Text = 'Pop-out';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.MultiuseLabel = uilabel(app.UIFigure);
            app.MultiuseLabel.Text = '';
            app.SaveButton_3 = uibutton(app.UIFigure, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Tag = 'SliceComponent';
            app.SaveButton_3.Text = 'Save';
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Tag = 'SliceComponent';
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = {};
            app.AdjustButton = uibutton(app.UIFigure, 'push');
            app.AdjustButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustButtonPushed, true);
            app.AdjustButton.Text = 'Adjust';
            app.DriftCorrectionLabel = uilabel(app.UIFigure);
            app.DriftCorrectionLabel.Text = 'Drift Correction';
            app.SignalScaleSpinnerLabel = uilabel(app.UIFigure);
            app.SignalScaleSpinnerLabel.Text = 'Signal Scale';
            app.SignalScaleSpinner = uispinner(app.UIFigure);
            app.SignalScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @SignalScaleSpinnerValueChanged, true);
            app.NoiseScaleSpinnerLabel = uilabel(app.UIFigure);
            app.NoiseScaleSpinnerLabel.Text = 'Noise Scale';
            app.NoiseScaleSpinner = uispinner(app.UIFigure);
            app.NoiseScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @NoiseScaleSpinnerValueChanged, true);
            app.ContourLinesSpinnerLabel = uilabel(app.UIFigure);
            app.ContourLinesSpinnerLabel.Text = '# Contour Lines';
            app.ContourLinesSpinner = uispinner(app.UIFigure);
            app.ContourLinesSpinner.Step = 10;
            app.ContourLinesSpinner.Limits = [10 100];
            app.ContourLinesSpinner.ValueChangedFcn = createCallbackFcn(app, @ContourLinesSpinnerValueChanged, true);
            app.ContourLinesSpinner.Value = 20;
            app.LinethicknessSpinnerLabel = uilabel(app.UIFigure);
            app.LinethicknessSpinnerLabel.Text = 'Line thickness';
            app.LinethicknessSpinner = uispinner(app.UIFigure);
            app.LinethicknessSpinner.Step = 0.5;
            app.LinethicknessSpinner.Limits = [0.5 5];
            app.LinethicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @LinethicknessSpinnerValueChanged, true);
            app.LinethicknessSpinner.Value = 1;
            app.ContourLineFillingSwitchLabel = uilabel(app.UIFigure);
            app.ContourLineFillingSwitchLabel.Text = 'Contour Line Filling';
            app.ContourLineFillingSwitch = uiswitch(app.UIFigure, 'rocker');
            app.ContourLineFillingSwitch.ValueChangedFcn = createCallbackFcn(app, @ContourLineFillingSwitchValueChanged, true);
            app.ColormapDropDown_2 = uidropdown(app.UIFigure);
            app.ColormapDropDown_2.Items = {};
            app.ColormapDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDown_2ValueChanged, true);
            app.ColormapDropDown_2.Value = {};
            app.F2rangeLabel = uilabel(app.UIFigure);
            app.F2rangeLabel.Text = 'F2 range:  ';
            app.F2rangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.F2rangeEditField.Limits = [0 2];
            app.F2rangeEditField.ValueChangedFcn = createCallbackFcn(app, @F2rangeEditFieldValueChanged, true);
            app.F2rangeEditField.Value = 1.5;
            app.toEditField_3Label = uilabel(app.UIFigure);
            app.toEditField_3Label.Text = 'to ';
            app.toEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_3.Limits = [0 80];
            app.toEditField_3.ValueChangedFcn = createCallbackFcn(app, @toEditField_3ValueChanged, true);
            app.toEditField_3.Value = 50;
            app.F1rangeEditFieldLabel = uilabel(app.UIFigure);
            app.F1rangeEditFieldLabel.Text = 'F1 range: ';
            app.F1rangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.F1rangeEditField.Limits = [-80 0];
            app.F1rangeEditField.RoundFractionalValues = 'on';
            app.F1rangeEditField.ValueChangedFcn = createCallbackFcn(app, @F1rangeEditFieldValueChanged, true);
            app.F1rangeEditField.Value = -50;
            app.toEditField_4Label = uilabel(app.UIFigure);
            app.toEditField_4Label.Text = 'to   ';
            app.toEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_4.Limits = [2 6];
            app.toEditField_4.ValueChangedFcn = createCallbackFcn(app, @toEditField_4ValueChanged, true);
            app.toEditField_4.Value = 4.5;
            app.F1HzEditFieldLabel = uilabel(app.UIFigure);
            app.F1HzEditFieldLabel.Text = 'F1 (Hz)';
            app.F1HzEditField = uieditfield(app.UIFigure, 'numeric');
            app.F2ppmEditFieldLabel = uilabel(app.UIFigure);
            app.F2ppmEditFieldLabel.Text = 'F2 (ppm)';
            app.F2ppmEditField = uieditfield(app.UIFigure, 'numeric');
            app.F2ppmEditField.Value = 2.01;
            app.WhereisDropDownLabel = uilabel(app.UIFigure);
            app.WhereisDropDownLabel.Text = 'Where is';
            app.WhereisDropDown = uidropdown(app.UIFigure);
            app.WhereisDropDown.Items = {};
            app.WhereisDropDown.ValueChangedFcn = createCallbackFcn(app, @WhereisDropDownValueChanged, true);
            app.WhereisDropDown.Value = {};
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.EnterNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterNameEditFieldLabel.Text = 'Enter Name';
            app.EnterNameEditField = uieditfield(app.UIFigure, 'text');
            app.BringtoLabel = uilabel(app.UIFigure);
            app.BringtoLabel.Text = 'Bring to:';
            app.F2AdjustField = uieditfield(app.UIFigure, 'numeric');
            app.F2AdjustField.Value = 2.01;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = ',';
            app.F1AdjustField = uieditfield(app.UIFigure, 'numeric');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MagnitudeButton = uiradiobutton(app.ButtonGroup);
            app.MagnitudeButton.Text = 'Magnitude';
            app.MagnitudeButton.Value = true;
            app.RealButton = uiradiobutton(app.ButtonGroup);
            app.RealButton.Text = 'Real';
            app.ImagButton = uiradiobutton(app.ButtonGroup);
            app.ImagButton.Text = 'Imag';
            app.PopoutButton_2 = uibutton(app.UIFigure, 'push');
            app.PopoutButton_2.ButtonPushedFcn = createCallbackFcn(app, @PopoutButton_2Pushed2, true);
            app.PopoutButton_2.Text = 'Pop-out';
            app.SaveButton_4 = uibutton(app.UIFigure, 'push');
            app.SaveButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_4Pushed, true);
            app.SaveButton_4.Text = 'Save';
            app.MetMapLabel = uilabel(app.UIFigure);
            app.MetMapLabel.Tag = 'SliceComponent';
            app.MetMapLabel.Text = 'MetMap  ';
            app.MetMapDropDown = uidropdown(app.UIFigure);
            app.MetMapDropDown.Items = {};
            app.MetMapDropDown.ValueChangedFcn = createCallbackFcn(app, @SliceComponentValueChanged, true);
            app.MetMapDropDown.Tag = 'SliceComponent';
            app.MetMapDropDown.Value = {};
            app.ScalewrtSpinnerLabel = uilabel(app.UIFigure);
            app.ScalewrtSpinnerLabel.Tag = 'SliceComponent';
            app.ScalewrtSpinnerLabel.Text = 'Scale w.r.t.';
            app.ScalewrtSpinner = uispinner(app.UIFigure);
            app.ScalewrtSpinner.Limits = [1 8];
            app.ScalewrtSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceComponentValueChanged, true);
            app.ScalewrtSpinner.Tag = 'SliceComponent';
            app.ScalewrtSpinner.Value = 5;
            app.SliceSpinnerLabel = uilabel(app.UIFigure);
            app.SliceSpinnerLabel.Tag = 'SliceComponent';
            app.SliceSpinnerLabel.Text = 'Slice #';
            app.SliceSpinner = uispinner(app.UIFigure);
            app.SliceSpinner.Limits = [1 8];
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceComponentValueChanged, true);
            app.SliceSpinner.Tag = 'SliceComponent';
            app.SliceSpinner.Value = 5;
            app.RangeppmEditFieldLabel = uilabel(app.UIFigure);
            app.RangeppmEditFieldLabel.Text = 'Range(ppm) ';
            app.RangeppmEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.toLabel = uilabel(app.UIFigure);
            app.toLabel.Text = 'to ';
            app.toEditField_3Label_2 = uilabel(app.UIFigure);
            app.toEditField_3Label_2.Text = 'Hz';
            app.toEditField_3Label_3 = uilabel(app.UIFigure);
            app.toEditField_3Label_3.Text = 'ppm';
            app.SelectSlicesPanel = uipanel(app.UIFigure);
            app.SelectSlicesPanel.Title = 'Select Slices';
            app.Tree = uitree(app.SelectSlicesPanel, 'checkbox');
            app.SaveallNode = uitreenode(app.Tree);
            app.SaveallNode.Text = 'Save all';
            app.Slice1Node = uitreenode(app.SaveallNode);
            app.Slice1Node.NodeData = 1;
            app.Slice1Node.Text = 'Slice 1';
            app.Slice2Node = uitreenode(app.SaveallNode);
            app.Slice2Node.NodeData = 2;
            app.Slice2Node.Text = 'Slice 2';
            app.Slice3Node = uitreenode(app.SaveallNode);
            app.Slice3Node.NodeData = 3;
            app.Slice3Node.Text = 'Slice 3';
            app.Slice4Node = uitreenode(app.SaveallNode);
            app.Slice4Node.NodeData = 4;
            app.Slice4Node.Text = 'Slice 4';
            app.Slice5Node = uitreenode(app.SaveallNode);
            app.Slice5Node.NodeData = 5;
            app.Slice5Node.Text = 'Slice 5';
            app.Slice6Node = uitreenode(app.SaveallNode);
            app.Slice6Node.NodeData = 6;
            app.Slice6Node.Text = 'Slice 6';
            app.Slice7Node = uitreenode(app.SaveallNode);
            app.Slice7Node.NodeData = 7;
            app.Slice7Node.Text = 'Slice 7';
            app.Slice8Node = uitreenode(app.SaveallNode);
            app.Slice8Node.NodeData = 8;
            app.Slice8Node.Text = 'Slice 8';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.Slice3Node, app.Slice4Node, app.Slice5Node];
            app.SaveButton_5 = uibutton(app.SelectSlicesPanel, 'push');
            app.SaveButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_5Pushed, true);
            app.SaveButton_5.Text = 'Save';
            app.ViewButton = uibutton(app.SelectSlicesPanel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.ButtonGroup_2 = uibuttongroup(app.SelectSlicesPanel);
            app.PDFButton = uiradiobutton(app.ButtonGroup_2);
            app.PDFButton.Text = 'PDF';
            app.PDFButton.Value = true;
            app.PPTButton = uiradiobutton(app.ButtonGroup_2);
            app.PPTButton.Text = 'PPT';
            app.TIFButton = uiradiobutton(app.ButtonGroup_2);
            app.TIFButton.Text = 'TIF';
            app.SaveFormatLabel = uilabel(app.SelectSlicesPanel);
            app.SaveFormatLabel.Text = 'Save Format';
            app.toEditFieldLabel = uilabel(app.SelectSlicesPanel);
            app.toEditFieldLabel.Visible = 'off';
            app.toEditFieldLabel.Text = 'to ';
            app.toEditField = uieditfield(app.SelectSlicesPanel, 'numeric');
            app.toEditField.Limits = [1 16];
            app.toEditField.Tag = 'SliceComponent';
            app.toEditField.Visible = 'off';
            app.toEditField.Value = 12;
            app.toEditField_2 = uieditfield(app.SelectSlicesPanel, 'numeric');
            app.toEditField_2.Limits = [1 16];
            app.toEditField_2.Visible = 'off';
            app.toEditField_2.Value = 13;
            app.toEditFieldLabel_2 = uilabel(app.SelectSlicesPanel);
            app.toEditFieldLabel_2.Visible = 'off';
            app.toEditFieldLabel_2.Text = 'to ';
            app.InputROIformultivoxelplotLabel = uilabel(app.SelectSlicesPanel);
            app.InputROIformultivoxelplotLabel.Visible = 'off';
            app.InputROIformultivoxelplotLabel.Text = 'Input ROI for multi-voxel plot';
            app.RowrangeEditFieldLabel = uilabel(app.SelectSlicesPanel);
            app.RowrangeEditFieldLabel.Visible = 'off';
            app.RowrangeEditFieldLabel.Text = 'Row range: ';
            app.ColumnrangeEditFieldLabel = uilabel(app.SelectSlicesPanel);
            app.ColumnrangeEditFieldLabel.Visible = 'off';
            app.ColumnrangeEditFieldLabel.Text = 'Column range: ';
            app.RowrangeEditField = uieditfield(app.SelectSlicesPanel, 'numeric');
            app.RowrangeEditField.Limits = [1 16];
            app.RowrangeEditField.RoundFractionalValues = 'on';
            app.RowrangeEditField.Tag = 'SliceComponent';
            app.RowrangeEditField.Visible = 'off';
            app.RowrangeEditField.Value = 4;
            app.ColumnrangeEditField = uieditfield(app.SelectSlicesPanel, 'numeric');
            app.ColumnrangeEditField.Limits = [1 16];
            app.ColumnrangeEditField.Tag = 'SliceComponent';
            app.ColumnrangeEditField.Visible = 'off';
            app.ColumnrangeEditField.Value = 5;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.ColormapDropDown_2Label = uilabel(app.UIFigure);
            app.ColormapDropDown_2Label.Text = 'Colormap';
            app.BackgroundLabel = uilabel(app.UIFigure);
            app.BackgroundLabel.Text = 'Background';
            app.adjustcontourlevelsButton = uibutton(app.UIFigure, 'push');
            app.adjustcontourlevelsButton.ButtonPushedFcn = createCallbackFcn(app, @adjustcontourlevelsButtonPushed, true);
            app.adjustcontourlevelsButton.Text = 'adjust contour levels';
            app.WaterSuppressionLabel = uilabel(app.UIFigure);
            app.WaterSuppressionLabel.Text = 'Water Suppression';
            app.WsColField2 = uieditfield(app.UIFigure, 'numeric');
            app.WsColField2.Limits = [1 16];
            app.WsColField2.Value = 13;
            app.toEditFieldLabel_3 = uilabel(app.UIFigure);
            app.toEditFieldLabel_3.Text = 'to ';
            app.ColsEditFieldLabel = uilabel(app.UIFigure);
            app.ColsEditFieldLabel.Text = 'Cols: ';
            app.WsColField1 = uieditfield(app.UIFigure, 'numeric');
            app.WsColField1.Limits = [1 16];
            app.WsColField1.Tag = 'SliceComponent';
            app.WsColField1.Value = 5;
            app.RowsEditFieldLabel = uilabel(app.UIFigure);
            app.RowsEditFieldLabel.Text = 'Rows: ';
            app.WsRowField1 = uieditfield(app.UIFigure, 'numeric');
            app.WsRowField1.Limits = [1 16];
            app.WsRowField1.RoundFractionalValues = 'on';
            app.WsRowField1.Tag = 'SliceComponent';
            app.WsRowField1.Value = 4;
            app.toEditField_7Label = uilabel(app.UIFigure);
            app.toEditField_7Label.Text = 'to ';
            app.WsRowField2 = uieditfield(app.UIFigure, 'numeric');
            app.WsRowField2.Limits = [1 16];
            app.WsRowField2.Tag = 'SliceComponent';
            app.WsRowField2.Value = 12;
            app.wsApplyButton = uibutton(app.UIFigure, 'push');
            app.wsApplyButton.ButtonPushedFcn = createCallbackFcn(app, @wsApplyButtonPushed, true);
            app.wsApplyButton.Text = 'Apply';
            app.wsRevertAllButton = uibutton(app.UIFigure, 'push');
            app.wsRevertAllButton.ButtonPushedFcn = createCallbackFcn(app, @wsRevertAllButtonPushed, true);
            app.wsRevertAllButton.Text = 'Revert All W.S.';
            app.WsSliceField2 = uieditfield(app.UIFigure, 'numeric');
            app.WsSliceField2.Limits = [1 8];
            app.WsSliceField2.Value = 5;
            app.toEditFieldLabel_4 = uilabel(app.UIFigure);
            app.toEditFieldLabel_4.Text = 'to ';
            app.SliceLabel = uilabel(app.UIFigure);
            app.SliceLabel.Text = 'Slice:';
            app.WsSliceField1 = uieditfield(app.UIFigure, 'numeric');
            app.WsSliceField1.Limits = [1 8];
            app.WsSliceField1.Tag = 'SliceComponent';
            app.WsSliceField1.Value = 5;
            app.CurrentDS = uilabel(app.UIFigure);
            app.CurrentDS.Text = 'Label2';
            app.infolabel = uilabel(app.UIFigure);
            app.infolabel.Text = 'Label3';
            app.GaussEditFieldLabel = uilabel(app.UIFigure);
            app.GaussEditFieldLabel.Text = 'Gauss:';
            app.GaussEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaussEditField.ValueChangedFcn = createCallbackFcn(app, @GaussEditFieldValueChanged, true);
            app.GaussEditField.Value = 2;
            app.ExpEditFieldLabel = uilabel(app.UIFigure);
            app.ExpEditFieldLabel.Text = 'Exp:';
            app.ExpEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExpEditField.ValueChangedFcn = createCallbackFcn(app, @ExpEditFieldValueChanged, true);
            app.ExpEditField.Value = 2;
            app.LinewidthLabel = uilabel(app.UIFigure);
            app.LinewidthLabel.Text = 'Linewidth';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = displayapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end