classdef exportapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SaveButton             matlab.ui.control.Button
        sl                     matlab.ui.control.NumericEditField
        SliceEditFieldLabel    matlab.ui.control.Label
        y                      matlab.ui.control.NumericEditField
        RowLabel               matlab.ui.control.Label
        x                      matlab.ui.control.NumericEditField
        ColLabel               matlab.ui.control.Label
        SaveRangeButtonGroup   matlab.ui.container.ButtonGroup
        SinglevoxeldataButton  matlab.ui.control.RadioButton
        AlldataButton          matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        % Description
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.y.Enable = "off";
            app.x.Enable = "off";
            app.sl.Enable = "off";
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            mainapp = app.CallingApp;
            if app.AlldataButton.Value
                ws_reconcs = mainapp.cdata;
                disp("saving entire dataset");
            else
                x = app.x.Value;
                y = app.y.Value;
                sl = app.sl.Value;
                ws_reconcs = squeeze(mainapp.cdata(y,x,sl,:,:));
                disp(strcat("selectively saving single-voxel: x: ", num2str(x), " y: ", num2str(y), " sl: ", num2str(sl)));
            end
             app.y.Enable = "off";
             app.x.Enable = "off";
             app.sl.Enable = "off";
             app.SaveRangeButtonGroup.Enable = "off";
             app.SaveButton.Enable = "off";
            [filename, filepath] = uiputfile('*.mat', 'Save the modified data file:');
            FileName = fullfile(filepath, filename);
            save(FileName, 'ws_reconcs', '-v7.3');
            clear ws_reconcs;
            disp(strcat(FileName, " saved."));
            app.y.Enable = "on ";
             app.x.Enable = "on";
             app.sl.Enable = "on";
             app.SaveRangeButtonGroup.Enable = "on";
             app.SaveButton.Enable = "on";
        end

        % Selection changed function: SaveRangeButtonGroup
        function SaveRangeButtonGroupSelectionChanged(app, event)
            if app.SinglevoxeldataButton.Value
                app.y.Enable = "on";
                app.x.Enable = "on";
                app.sl.Enable = "on";
            else
                 app.y.Enable = "off";
                app.x.Enable = "off";
                app.sl.Enable = "off";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveRangeButtonGroup = uibuttongroup(app.UIFigure);
            app.SaveRangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SaveRangeButtonGroupSelectionChanged, true);
            app.SaveRangeButtonGroup.Title = 'Save Range';
            app.AlldataButton = uiradiobutton(app.SaveRangeButtonGroup);
            app.AlldataButton.Text = 'All data';
            app.AlldataButton.Value = true;
            app.SinglevoxeldataButton = uiradiobutton(app.SaveRangeButtonGroup);
            app.SinglevoxeldataButton.Text = 'Single-voxel data';
            app.ColLabel = uilabel(app.UIFigure);
            app.ColLabel.Text = 'Col';
            app.x = uieditfield(app.UIFigure, 'numeric');
            app.RowLabel = uilabel(app.UIFigure);
            app.RowLabel.Text = 'Row';
            app.y = uieditfield(app.UIFigure, 'numeric');
            app.SliceEditFieldLabel = uilabel(app.UIFigure);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.sl = uieditfield(app.UIFigure, 'numeric');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exportapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end