classdef inputapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ZeropointofF2axisppmEditField  matlab.ui.control.NumericEditField
        ZeropointofF2axisppmLabel      matlab.ui.control.Label
        MessageButton                  matlab.ui.control.Button
        LarmorFrequencyMHzEditField    matlab.ui.control.NumericEditField
        LarmorFrequencyMHzEditFieldLabel  matlab.ui.control.Label
        ZeropointofF1axisHzEditField   matlab.ui.control.NumericEditField
        ZeropointofF1axisHzLabel       matlab.ui.control.Label
        BandwidthalongF2HzEditField    matlab.ui.control.NumericEditField
        BandwidthalongF2HzEditField_2Label  matlab.ui.control.Label
        BandwidthalongF1HzEditField    matlab.ui.control.NumericEditField
        BandwidthalongF1HzEditField_2Label  matlab.ui.control.Label
        ApplyButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, bw1, bw2, zp1, zp2, T)
            
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            app.UIFigure.Visible = 'on';
            
            app.MessageButton.Visible = 'off';
            app.MessageButton.Enable  = 'off';

            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.BandwidthalongF1HzEditField.Value    = bw1;
            app.BandwidthalongF2HzEditField.Value    = bw2;
            app.ZeropointofF1axisHzEditField.Value   = zp1;
            app.ZeropointofF2axisppmEditField.Value  = zp2;
            app.LarmorFrequencyMHzEditField.Value    = T;
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)

            %call display app
            app.ApplyButton.Enable                     = 'off';
            app.ApplyButton.Visible                    = 'off';
            app.BandwidthalongF1HzEditField.Editable   = 'off';
            app.BandwidthalongF2HzEditField.Editable   = 'off';
            app.ZeropointofF1axisHzEditField.Editable  = 'off';
            app.ZeropointofF2axisppmEditField.Editable = 'off';
            app.LarmorFrequencyMHzEditField.Editable   = 'off';
            
            app.MessageButton.Visible                  = 'on';
            drawnow;

           % Call main app's public function
           updateparams(app.CallingApp, app.BandwidthalongF1HzEditField.Value,...
               app.BandwidthalongF2HzEditField.Value,app.ZeropointofF1axisHzEditField.Value,...
               app.ZeropointofF2axisppmEditField.Value,app.LarmorFrequencyMHzEditField.Value);
           updateapp(app.CallingApp);

           % Delete the dialog box
           delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            warning('off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.BandwidthalongF1HzEditField_2Label = uilabel(app.GridLayout);
            app.BandwidthalongF1HzEditField_2Label.Text = 'Bandwidth along F1(Hz)';
            app.BandwidthalongF1HzEditField = uieditfield(app.GridLayout, 'numeric');
            app.BandwidthalongF2HzEditField_2Label = uilabel(app.GridLayout);
            app.BandwidthalongF2HzEditField_2Label.Text = 'Bandwidth along F2 (Hz)';
            app.BandwidthalongF2HzEditField = uieditfield(app.GridLayout, 'numeric');
            app.ZeropointofF1axisHzLabel = uilabel(app.GridLayout);
            app.ZeropointofF1axisHzLabel.Text = 'Zero point of F1-axis (Hz) ';
            app.ZeropointofF1axisHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.LarmorFrequencyMHzEditFieldLabel = uilabel(app.GridLayout);
            app.LarmorFrequencyMHzEditFieldLabel.Text = 'Larmor Frequency (MHz)';
            app.LarmorFrequencyMHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.MessageButton = uibutton(app.GridLayout, 'push');
            app.MessageButton.Text = 'Processing request.. Please wait!!';
            app.ZeropointofF2axisppmLabel = uilabel(app.GridLayout);
            app.ZeropointofF2axisppmLabel.Text = 'Zero point of F2-axis (ppm) ';
            app.ZeropointofF2axisppmEditField = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inputapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end